classdef winSCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        menu_Grid                       matlab.ui.container.GridLayout
        jsBackDoor                      matlab.ui.control.HTML
        AppInfo                         matlab.ui.control.Image
        FigurePosition                  matlab.ui.control.Image
        DataHubLamp                     matlab.ui.control.Lamp
        report_ReportPreview            matlab.ui.control.Image
        report_PanelVisibility          matlab.ui.control.Image
        search_PanelVisibility          matlab.ui.control.Image
        menu_Button4                    matlab.ui.control.StateButton
        menu_Button3                    matlab.ui.control.StateButton
        menu_Button2                    matlab.ui.control.StateButton
        menu_Button1                    matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_Home                       matlab.ui.container.Tab
        home_mainGrid                   matlab.ui.container.GridLayout
        home_GIF                        matlab.ui.control.Image
        home_Title                      matlab.ui.control.Label
        Tab2_Search                     matlab.ui.container.Tab
        search_mainGrid                 matlab.ui.container.GridLayout
        search_Control                  matlab.ui.container.GridLayout
        search_ControlMenu              matlab.ui.container.GridLayout
        search_menuBtn2Grid             matlab.ui.container.GridLayout
        search_menuBtn2Icon             matlab.ui.control.Image
        search_menuBtn2Label            matlab.ui.control.Label
        search_menuBtn1Grid             matlab.ui.container.GridLayout
        search_menuBtn1Icon             matlab.ui.control.Image
        search_menuBtn1Label            matlab.ui.control.Label
        search_menuUnderline            matlab.ui.control.Image
        search_ControlTabGroup          matlab.ui.container.TabGroup
        search_Tab1                     matlab.ui.container.Tab
        search_Tab1Grid                 matlab.ui.container.GridLayout
        search_ToolbarAnnotation        matlab.ui.control.Image
        search_ListOfProducts           matlab.ui.control.ListBox
        search_ListOfProductsAdd        matlab.ui.control.Image
        search_ListOfProductsLabel      matlab.ui.control.Label
        search_WordCloudRefreshGrid     matlab.ui.container.GridLayout
        search_WordCloudRefresh         matlab.ui.control.Image
        search_WordCloudPanel           matlab.ui.container.Panel
        search_AnnotationPanel          matlab.ui.container.Panel
        search_AnnotationGrid           matlab.ui.container.GridLayout
        search_AnnotationPanelAdd       matlab.ui.control.Image
        search_AnnotationValue          matlab.ui.control.TextArea
        search_AnnotationValueLabel     matlab.ui.control.Label
        search_AnnotationAttribute      matlab.ui.control.DropDown
        search_AnnotationAttributeLabel  matlab.ui.control.Label
        search_AnnotationPanelLabel     matlab.ui.control.Label
        search_ProductInfoPanel         matlab.ui.container.Panel
        search_ProductInfoGrid          matlab.ui.container.GridLayout
        search_ProductInfo              matlab.ui.control.HTML
        search_ToolbarListOfProducts    matlab.ui.control.Image
        search_ToolbarWordCloud         matlab.ui.control.Image
        search_ProductInfoLabel         matlab.ui.control.Label
        search_Tab2                     matlab.ui.container.Tab
        search_Tab2Grid                 matlab.ui.container.GridLayout
        search_SecundaryPanel           matlab.ui.container.Panel
        search_SecundaryGrid            matlab.ui.container.GridLayout
        search_Status                   matlab.ui.control.DropDown
        search_StatusCheckBox           matlab.ui.control.CheckBox
        search_TradeName                matlab.ui.control.EditField
        search_TradeNameCheckBox        matlab.ui.control.CheckBox
        search_Model                    matlab.ui.control.EditField
        search_ModelCheckBox            matlab.ui.control.CheckBox
        search_Manufacturer             matlab.ui.control.EditField
        search_ManufacturerCheckBox     matlab.ui.control.CheckBox
        search_CNPJ                     matlab.ui.control.EditField
        search_CNPJCheckBox             matlab.ui.control.CheckBox
        search_Applicant                matlab.ui.control.EditField
        search_ApplicantCheckBox        matlab.ui.control.CheckBox
        search_ProductList              matlab.ui.control.DropDown
        search_ProductListCheckBox      matlab.ui.control.CheckBox
        search_Certification            matlab.ui.control.EditField
        search_CertificationCheckBox    matlab.ui.control.CheckBox
        search_SecundaryPanelLabel      matlab.ui.control.Label
        search_PrimaryPanel             matlab.ui.container.ButtonGroup
        search_PrimaryOption3           matlab.ui.control.RadioButton
        search_PrimaryOption2           matlab.ui.control.RadioButton
        search_PrimaryOption1           matlab.ui.control.RadioButton
        search_PrimaryPanelLabel        matlab.ui.control.Label
        search_Document                 matlab.ui.container.GridLayout
        search_Suggestions              matlab.ui.control.ListBox
        search_nRows                    matlab.ui.control.Label
        search_Metadata                 matlab.ui.control.Label
        search_entryPointPanel          matlab.ui.container.Panel
        search_entryPointGrid           matlab.ui.container.GridLayout
        search_entryPointImage          matlab.ui.control.Image
        search_entryPoint               matlab.ui.control.EditField
        search_Table                    matlab.ui.control.Table
        Tab3_Report                     matlab.ui.container.Tab
        report_mainGrid                 matlab.ui.container.GridLayout
        report_Control                  matlab.ui.container.GridLayout
        report_ControlMenu              matlab.ui.container.GridLayout
        report_menuBtn2Grid             matlab.ui.container.GridLayout
        report_menuBtn2Icon             matlab.ui.control.Image
        report_menuBtn2Label            matlab.ui.control.Label
        report_menuBtn1Grid             matlab.ui.container.GridLayout
        report_menuBtn1Icon             matlab.ui.control.Image
        report_menuBtn1Label            matlab.ui.control.Label
        report_menuUnderline            matlab.ui.control.Image
        report_ControlTabGroup          matlab.ui.container.TabGroup
        report_Tab1                     matlab.ui.container.Tab
        report_Tab1Grid                 matlab.ui.container.GridLayout
        report_ToolbarEditOrAdd         matlab.ui.control.Image
        report_EditableInfoPanel        matlab.ui.container.Panel
        report_EditableInfoGrid         matlab.ui.container.GridLayout
        report_nHom                     matlab.ui.control.EditField
        report_nHomLabel                matlab.ui.control.Label
        report_EditOrAddButton          matlab.ui.control.Image
        report_Notes                    matlab.ui.control.TextArea
        report_NotesLabel               matlab.ui.control.Label
        report_Violation                matlab.ui.control.DropDown
        report_ViolationLabel           matlab.ui.control.Label
        report_Situation                matlab.ui.control.DropDown
        report_SituationLabel           matlab.ui.control.Label
        report_productSituationPanel    matlab.ui.container.ButtonGroup
        report_productSituation2        matlab.ui.control.RadioButton
        report_productSituation1        matlab.ui.control.RadioButton
        report_EditableInfoLabel        matlab.ui.control.Label
        report_ProductInfoPanel         matlab.ui.container.Panel
        report_ProductInfoGrid          matlab.ui.container.GridLayout
        report_ProductInfo              matlab.ui.control.HTML
        report_ProductInfoLabel         matlab.ui.control.Label
        report_Tab2                     matlab.ui.container.Tab
        report_Tab2Grid                 matlab.ui.container.GridLayout
        report_ReportPanel              matlab.ui.container.Panel
        report_ReportGrid               matlab.ui.container.GridLayout
        report_Version                  matlab.ui.control.DropDown
        report_VersionLabel             matlab.ui.control.Label
        report_Model                    matlab.ui.control.DropDown
        report_ModelLabel               matlab.ui.control.Label
        report_ReportLabel              matlab.ui.control.Label
        report_FiscalizaPanel           matlab.ui.container.Panel
        report_FiscalizaGrid            matlab.ui.container.GridLayout
        report_EntityType               matlab.ui.control.DropDown
        report_EntityTypeLabel          matlab.ui.control.Label
        report_EntityID                 matlab.ui.control.EditField
        report_EntityIDLabel            matlab.ui.control.Label
        report_Entity                   matlab.ui.control.EditField
        report_EntityLabel              matlab.ui.control.Label
        report_Issue                    matlab.ui.control.NumericEditField
        report_IssueLabel               matlab.ui.control.Label
        report_FiscalizaLabel           matlab.ui.control.Label
        report_ProjectPanel             matlab.ui.container.Panel
        report_ProjectGrid              matlab.ui.container.GridLayout
        report_ProjectName              matlab.ui.control.TextArea
        report_ProjectSave              matlab.ui.control.Image
        report_ProjectOpen              matlab.ui.control.Image
        report_ProjectNew               matlab.ui.control.Image
        report_ProjectNameLabel         matlab.ui.control.Label
        report_ProjectLabel             matlab.ui.control.Label
        report_Document                 matlab.ui.container.GridLayout
        report_nRows                    matlab.ui.control.Label
        report_Table                    matlab.ui.control.Table
        report_TableLabel               matlab.ui.control.Label
        Tab4_Config                     matlab.ui.container.Tab
        config_mainGrid                 matlab.ui.container.GridLayout
        config_FolderMapPanel           matlab.ui.container.Panel
        config_FolderMapGrid            matlab.ui.container.GridLayout
        config_Folder_UserPathButton    matlab.ui.control.Image
        config_Folder_UserPath          matlab.ui.control.DropDown
        config_Folder_UserPathLabel     matlab.ui.control.Label
        config_Folder_DataHubPOSTButton  matlab.ui.control.Image
        config_Folder_DataHubPOST       matlab.ui.control.EditField
        config_Folder_DataHubPOSTLabel  matlab.ui.control.Label
        config_Folder_DataHubGETButton  matlab.ui.control.Image
        config_Folder_DataHubGET        matlab.ui.control.EditField
        config_Folder_DataHubGETLabel   matlab.ui.control.Label
        config_SelectedTableColumns     matlab.ui.container.CheckBoxTree
        config_SelectedTableColumnsLabel  matlab.ui.control.Label
        config_FolderMapLabel           matlab.ui.control.Label
        config_MiscelaneousPanel2       matlab.ui.container.Panel
        config_MiscelaneousGrid2        matlab.ui.container.GridLayout
        config_WordCloudNumber          matlab.ui.control.DropDown
        config_WordCloudNumberLabel     matlab.ui.control.Label
        config_WordCloudColumn          matlab.ui.control.DropDown
        config_WordCloudColumnLabel     matlab.ui.control.Label
        config_MiscelaneousLabel2       matlab.ui.control.Label
        config_MiscelaneousPanel1       matlab.ui.container.Panel
        config_MiscelaneousGrid1        matlab.ui.container.GridLayout
        config_nMinWords                matlab.ui.control.DropDown
        config_nMinWordsLabel           matlab.ui.control.Label
        config_nMinCharacters           matlab.ui.control.Spinner
        config_nMinCharactersLabel      matlab.ui.control.Label
        config_MiscelaneousLabel1       matlab.ui.control.Label
        config_SearchModePanel          matlab.ui.container.ButtonGroup
        config_SearchModeListOfWords    matlab.ui.control.RadioButton
        config_SearchModeTokenSuggestion  matlab.ui.control.RadioButton
        config_SearchModeLabel          matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        ContextMenu_DeleteFcn           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        General

        executionMode                                                       % 'built-in' | 'desktopApp' | 'webApp'
        rootFolder

        timerObj_startup

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag   = true(4, 1);

        rawDataTable
        releasedData
        cacheData

        annotationTable
        annotationBackupFlag

        listOfProducts
        
        previousSearch   = ''
        previousItemData = 0

        wordCloudChart
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)
                
                % Leitura de arquivos externos ("GeneralSettings.json", por
                % exemplo) e inicialização do módulo de operação paralelo.
                startup_ReadFiles(app)
                fcn.parpoolCheck()

                % Salva na propriedade "UserData" as opções de ícone e o índice 
                % da aba, simplificando os ajustes decorrentes de uma alteração...
                app.menu_Button1.UserData                 = struct('iconOptions', {{'Home_32White.png',      'Home_32Yellow.png'}},      'tabGroup', 1);
                app.menu_Button2.UserData                 = struct('iconOptions', {{'Zoom_32White.png',      'Zoom_32Yellow.png'}},      'tabGroup', 2);
                app.menu_Button3.UserData                 = struct('iconOptions', {{'Detection_32White.png', 'Detection_32Yellow.png'}}, 'tabGroup', 3);
                app.menu_Button4.UserData                 = struct('iconOptions', {{'Settings_36White.png',  'Settings_36Yellow.png'}},  'tabGroup', 4);

                app.search_ToolbarAnnotation.UserData     = struct('iconOptions', {{'Edit_18x18Gray.png',    'Edit_18x18Blue.png'}});
                app.search_ToolbarWordCloud.UserData      = struct('iconOptions', {{'Cloud_32x32Gray.png',   'Cloud_32x32Blue.png'}});
                app.search_ToolbarListOfProducts.UserData = struct('iconOptions', {{'Box_32x32Gray.png',     'Box_32x32Blue.png'}});

                app.report_ToolbarEditOrAdd.UserData      = struct('iconOptions', {{'Edit_18x18Gray.png',    'Edit_18x18Blue.png'}});
                app.report_EditOrAddButton.UserData       = struct('iconOptions', {{'Edit_36.png',           'NewFile_36.png'}});

                % Habilita botões do menu gráfico principal, atualiza propriedades 
                % de alguns dos componentes da GUI e customiza as aspectos estéticos
                % de alguns dos componentes da GUI (diretamente em JS).
                set(findobj(app.menu_Grid.Children, '-not', 'Type', 'uihtml'), 'Enable', 1)
                startup_LayoutComponents(app)
                jsBackDoor_Customizations(app, 0)
                
                % Define tamanho mínimo da GUI (aplicável apenas para a versão 
                % desktop do app.
                ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);
                app.UIFigure.Resize = "on";
            end
        end
        

        %-----------------------------------------------------------------%
        function startup_ReadFiles(app)

            % Verifica se a pasta de configuração já foi criada e se lá tem 
            % o arquivo de configuração "GeneralSettings.json".
            %
            % Compara-se a versão dos arquivos hospedados nas pastas local e 
            % de configuração.
            % - Local:  fullfile(ctfroot, 'Settings')
            % - Config: fullfile(getenv('PROGRAMDATA'), 'ANATEL', 'SCH') (Windows)
            %
            % Caso o arquivo hospedado na pasta local possua uma versão mais 
            % recente, substitui-se o hospedado na pasta de configuração,
            % mantendo, contudo, os mapeamentos de pastas (usuário, Python,
            % DataHub_GETm DataHub_POST etc).
            %
            % Isso é especialmente importante para os webapps, cujo arquivo
            % executado pelo MATLAB WEBSERVER não é um executável, com seu
            % conjunto de subpastas e arquivos de suporte, mas um CTF.
            %
            % Logo, qualquer alteração num arquivo relacionado a um CTF será 
            % perdida quando for encerrada a sessão do webapp.

            % app.General
            [app.General, msgError] = readFile.GeneralSettings(app.rootFolder);            
            if ~isempty(msgError)
                layout.modalWindow(app.UIFigure, 'error', msgError);
            end

            % app.rawDataTable
            % app.releaseData
            % app.cacheData
            DataHub_GET     = app.General.fileFolder.DataHub_GET;
            SCHDataFileName = app.General.fileName.SCHData;

            SCHDataFullFile = fullfile(DataHub_GET, SCHDataFileName);
            if ~isfile(SCHDataFullFile)
                SCHDataFullFile = fullfile(app.rootFolder, 'DataBase', SCHDataFileName);
            end

            [app.rawDataTable, app.releasedData, app.cacheData, matFullFile] = readFile.SCHData(SCHDataFullFile);

            % !! PONTO DE EVOLUÇÃO !!
            if ~isempty(matFullFile)
                [~, fileMATName] = fileparts(fileName);
                fileMATName      = [fileMATName '.mat'];

                app.General.fileName.SCHData = fileMATName;
                writeFile.GeneralSettings(app.General, app.rootFolder)
            end
            % !! PONTO DE EVOLUÇÃO !!

            % app.annotationTable
            [app.annotationTable, app.annotationBackupFlag, msgWarning] = readFile.Annotation(app.rootFolder, DataHub_GET);
            if ~isempty(msgWarning)
                layout.modalWindow(app.UIFigure, 'warning', msgWarning);
            end

            % app.listOfProducts
            app.listOfProducts = readFile.ListOfProducts(app.General);
        end


        %-----------------------------------------------------------------%
        function startup_LayoutComponents(app)
            % FOLDERS
            DataHub_GET  = app.General.fileFolder.DataHub_GET;
            DataHub_POST = app.General.fileFolder.DataHub_POST;
            
            if isfolder(DataHub_GET)
                app.config_Folder_DataHubGET.Value  = DataHub_GET;
            end

            if isfolder(DataHub_POST)
                app.config_Folder_DataHubPOST.Value = DataHub_POST;
            end
            config_DataHubWarningLamp(app)

            userPaths = fcn.UserPaths(app.rootFolder, app.General.fileFolder.userPath);
            set(app.config_Folder_UserPath, 'Items', userPaths, 'Value', userPaths{end})
            app.General.fileFolder.userPath = userPaths{end};

            % TABLE VISIBLE COLUMNS (TREE)
            allColumns      = class.Constants.search_TableColumnNames;
            staticColumns   = class.Constants.search_TableStaticColumns;

            [~, sortIndex]  = sort(lower(allColumns));
            GUIAllColumns   = allColumns(sortIndex);

            listOfTreeNodes = cellfun(@(x) uitreenode(app.config_SelectedTableColumns, 'Text', x), GUIAllColumns);
            isStaticColumn  = ismember(GUIAllColumns, staticColumns);            

            app.config_SelectedTableColumns.CheckedNodes = listOfTreeNodes(isStaticColumn);
            addStyle(app.config_SelectedTableColumns, class.Constants.configStyle5, 'node', listOfTreeNodes(isStaticColumn))
        end


        %-----------------------------------------------------------------%
        function menu_LayoutControl(app, tabIndex)

            % Função reservada para ajustar a visibilidade de componentes,
            % ao trocar o modo de operação do app. (clique nos botões do
            % menu gráfico principal)

            switch tabIndex
                case 1 % HOME
                    app.search_PanelVisibility.Visible = 0;
                    app.report_PanelVisibility.Visible = 0;

                case 2 % SEARCH
                    app.search_PanelVisibility.Visible = 1;
                    app.report_PanelVisibility.Visible = 0;

                case 3 % REPORT
                    app.search_PanelVisibility.Visible = 0;
                    app.report_PanelVisibility.Visible = 1;

                case 4 % CONFIG
                    app.search_PanelVisibility.Visible = 0;
                    app.report_PanelVisibility.Visible = 0;
            end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = sprintf('<!DOCTYPE html>\n<html>\n<head>\n<script>\n%s\n</script>\n</head>\n\n<body>\n</body>\n</html>', fileread(fullfile(ccTools.Path, 'css&js', 'compCustomization.js')));
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_keyDownListener(app, evt);
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_keyDownListener(app, event)

            % Foi adicionado o evento JS-keydown das teclas ["ArrowUp", "ArrowDown", "Enter", "Escape"]
            % aos componentes app.search_entryPoint (matlab.ui.control.EditField) e app.search_Suggestions
            % (matlab.ui.control.ListBox) usando o JS-backdoor app.jsBackDoor (matlab.ui.control.HTML).
    
            % Em relação aos callbacks configuráveis no próprio MATLAB:
            % - matlab.ui.control.EditField 
            %   (a) Possui os eventos "ValueChangedFcn" e "ValueChangingFcn".
            %   (b) Não responde à tecla "Escape".
            %   (c) Responde às teclas "ArrowUp" e "ArrowDown", controlando a posição 
            %       do cursor (início e fim, respectivamente)
            %   (d) Reponde à tecla "Enter", executando "ValueChangingFcn" e "ValueChangedFcn", 
            %       nesse ordem.
    
            % - matlab.ui.control.ListBox
            %   (a) possui os eventos "ValueChangedFcn", "ClickedFcn" e "DoubleClickedFcn".
            %   (b) Responde às teclas "ArrowUp" e "ArrowDown", executando "ValueChangedFcn", 
            %       desde que não estejam selecionadas as suas "bordas" (valor 1 e "ArrowUp", 
            %       ou valor n e "ArrowDown", por exemplo).
    
            % Num eventual clique de uma das teclas ["ArrowUp", "ArrowDown", "Enter", "Escape"],
            % o trigger do evento JS-keydown ocorre antes do trigger dos eventos padrões dos 
            % componentes matlab.ui.control.EditField e matlab.ui.control.ListBox.
    
            % Isso é bom, mas ruim por criar uma complexidade extra! :(
    
            % Quando altero o conteúdo de app.search_entryPoint, sem alterar o seu foco, será executado 
            % o evento "ValueChangingFcn". Se pressiono a tecla "Enter", será executada essa função 
            % (jsBackDoor_keyDownListener) antes de atualizar a propriedade "Value" do app.search_entryPoint.
    
            % Por conta disso, é essencial inserir waitfor(app.search_entryPoint, 'Value')
            % Isso é conseguido alterando o objeto em foco, de app.search_entryPoint para app.jsBackDoor
            % Ao fazer isso, o MATLAB "executa" a seguinte operação:
            % app.search_entryPoint.Value = app.search_entryPoint.ChangingValue

            switch event.HTMLEventData
                case 'app.search_entryPoint'
                    focus(app.jsBackDoor)

                    switch event.HTMLEventName
                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})

                            search_EntryPoint_CheckIfNeedsUpdate(app)
                            if numel(app.search_entryPoint.Value) < app.config_nMinCharacters.Value
                                search_EntryPoint_InitialValue(app)
                            end

                        otherwise
                            search_EntryPoint_CheckIfNeedsUpdate(app)
                            if numel(app.search_entryPoint.Value) < app.config_nMinCharacters.Value
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));

                            else
                                switch event.HTMLEventName
                                    case 'ArrowDown'
                                        if app.config_SearchModeTokenSuggestion.Value
                                            app.previousItemData = 1;
                
                                            set(app.search_Suggestions, 'Visible', 1, 'Value', 1)
                                            scroll(app.search_Suggestions, "top")
                                            focus(app.search_Suggestions)
                                        end
                    
                                    case 'ArrowUp'
                                        if app.config_SearchModeTokenSuggestion.Value
                                            nMaxValues = numel(app.search_Suggestions.Items);
    
                                            app.previousItemData = nMaxValues;            
                                            set(app.search_Suggestions, 'Visible', 1, 'Value', nMaxValues)
                                            scroll(app.search_Suggestions, "bottom")
                                            focus(app.search_Suggestions)
                                        end

                                    case 'Enter'                                        
                                        set(app.search_Suggestions, Visible=0, Value={})
                                        search_EntryPoint_ImageClicked(app)
                                        % sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                                end
                            end
                    end

                %---------------------------------------------------------%
                case 'app.search_Suggestions'
                    switch event.HTMLEventName
                        case 'ArrowDown'
                            nMaxValues = numel(app.search_Suggestions.Items);

                            if (app.previousItemData == nMaxValues) && (app.search_Suggestions.Value == nMaxValues)
                                app.previousItemData = 0;
                                
                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousItemData = app.search_Suggestions.Value;
                            end
        
                        case 'ArrowUp'
                            if (app.previousItemData == 1) && (app.search_Suggestions.Value == 1)
                                app.previousItemData = 0;

                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousItemData = app.search_Suggestions.Value;
                            end
        
                        case 'Enter'
                            eventValue = app.search_Suggestions.Items{app.search_Suggestions.Value};

                            app.search_entryPoint.Value = eventValue;
                            search_SuggestionAlgorithm(app, eventValue, false)
                            
                            sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));

                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})
                    end
            end
            drawnow
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            switch tabIndex
                case 0 % STARTUP
                    % Força a exclusão da tela de progresso da inicialização de um 
                    % webapp, caso aplicável.
                    sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");

                    % .mw-default-header-cell {
                    %    font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;       /* UITABLE TABLE HEADER                                      */ 
                    % }
                    % .mw-theme-light {
                    %    --mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); /* UITABLE BACKGROUND SELECTED CELL (WITHOUT FOCUS)          */
                    %    --mw-backgroundColor-selected: rgb(180 222 255 / 45%);            /* UILISTBOX BACKGROUND SELECTED CELL (WITHOUT FOCUS)        */
                    %    --mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);       /* UILISTBOX BACKGROUND SELECTED CELL                        */
                    %    --mw-borderColor-focus: #7d7d7d;                                  /* UIBUTTON, UITABLE, UILISTBOX, UIIMAGE BORDER COLOR        */
                    %    --mw-borderColor-primary: var(--mw-color-gray600);                /* UIBUTTON, UITABLE, UILISTBOX BORDER COLOR (WITHOUT FOCUS) */
                    % }
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell',                                           ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'))
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']))
                    ccTools.compCustomizationV2(app.jsBackDoor, app.TabGroup, 'transparentHeader', 'transparent')
                    drawnow

                case 1 % HOME
                    if app.jsBackDoorFlag(tabIndex)
                        app.jsBackDoorFlag(tabIndex) = false;
                        % ...
                    end

                case 2 % SEARCH
                    if app.jsBackDoorFlag(tabIndex)
                        app.jsBackDoorFlag(tabIndex) = false;
        
                        % Cria keydown listeners, em JS, para os componentes descritos
                        % em objList.
                        objList = {app.search_entryPoint, app.search_Suggestions};
                        for ii = 1:numel(objList)
                            objList{ii}.UserData = struct(objList{ii}).Controller.ViewModel.Id;
                        end            
                        sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_entryPoint',  'DataTag', app.search_entryPoint.UserData));
                        sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_Suggestions', 'DataTag', app.search_Suggestions.UserData));
            
                        % Aplica customizações estéticas ao(s) componente(s).
                        ccTools.compCustomizationV2(app.jsBackDoor, app.search_ControlTabGroup,      'transparentHeader', 'transparent')
                        ccTools.compCustomizationV2(app.jsBackDoor, app.search_entryPoint,           'borderWidth', '0px');
                        ccTools.compCustomizationV2(app.jsBackDoor, app.search_WordCloudRefreshGrid, 'backgroundColor', 'transparent');
                        drawnow
                    end

                case 3 % REPORT
                    if app.jsBackDoorFlag(tabIndex)
                        app.jsBackDoorFlag(tabIndex) = false;

                        % Aplica customizações estéticas ao(s) componente(s).
                        ccTools.compCustomizationV2(app.jsBackDoor, app.report_ControlTabGroup, 'transparentHeader', 'transparent')
                        ccTools.compCustomizationV2(app.jsBackDoor, app.report_Notes,           'textAlign', 'justify')
                    end

                case 4 % CONFIG
                    if app.jsBackDoorFlag(tabIndex)
                        app.jsBackDoorFlag(tabIndex) = false;
                        % (...)
                    end
            end
        end


        %-----------------------------------------------------------------%
        function search_SuggestionAlgorithm(app, eventValue, panelVisibility)
            value2Search = fcn.PreProcessedData(eventValue);
            search_EntryPointImage_Status(app, value2Search)

            if app.config_SearchModeTokenSuggestion.Value
                if (numel(value2Search) >= app.config_nMinCharacters.Value) && ~strcmp(eventValue, app.previousSearch)
                    listOfColumns = search_Filtering_primaryTableColumns(app);
                    nMinValues    = str2double(app.config_nMinWords.Value);

                    [similarStrings, idxFiltered, redFontFlag] = suggestion.getSimilarStrings(app.cacheData, value2Search, listOfColumns, nMinValues);

                    set(app.search_Suggestions, 'Visible', panelVisibility, 'Value', {}, 'Items', similarStrings, 'ItemsData', 1:numel(idxFiltered))
                    search_EntryPoint_Color(app, redFontFlag)
                end
            end

            app.previousSearch = eventValue;
        end


        %-----------------------------------------------------------------%
        function relatedAnnotationTable = search_Annotation_RelatedTable(app, showedHom)
            annotationLogical      = strcmp(app.annotationTable.("Homologação"), showedHom);
            relatedAnnotationTable = app.annotationTable(annotationLogical, :);
        end


        %-----------------------------------------------------------------%
        function search_Annotation_Add2Cache(app, showedHom, attributeName, attributeValue, wourdCloudRefreshTag)
            % !! PONTO DE EVOLUÇÃO !!
            % IMPLEMENTADA ADICIONAR UMA ANOTAÇÃO, ALÉM DE EDITAR UMA ANOTAÇÃO 
            % DE WORDCLOUD. PENDENTE EXCLUSÃO DE UMA ANOTAÇÃO, ALÉM DA EDIÇÃO
            % DOS OUTROS TIPOS DE ANOTAÇÃO.
            newRowTable = table({char(matlab.lang.internal.uuid())},   ...
                                {datestr(now, 'dd/mm/yyyy HH:MM:SS')}, ...
                                {getenv("COMPUTERNAME")},              ...
                                {getenv("USERNAME")},                  ...
                                {showedHom},                           ...
                                {attributeName},                       ...
                                {attributeValue},                      ...
                                1, 'VariableNames', class.Constants.annotationTableColumns);

            idx1 = find(strcmp(app.annotationTable.("Homologação"), showedHom))';            
            if isempty(idx1) || wourdCloudRefreshTag
                app.annotationTable(end+1,:) = newRowTable;

            else
                if any(strcmp(app.annotationTable.("Atributo")(idx1), attributeName) & strcmp(app.annotationTable.("Valor")(idx1), attributeValue))
                    layout.modalWindow(app.UIFigure, 'warning', sprintf('Conjunto atributo/valor já consta como anotação do registro %s.', showedHom));
                    return

                else
                    app.annotationTable(end+1,:) = newRowTable;
                end
            end

            if wourdCloudRefreshTag
                idx2 = find(strcmp(app.annotationTable.("Homologação"), showedHom) & strcmp(app.annotationTable.("Atributo"), 'WordCloud'));
                app.annotationTable(idx2(1:end-1), :) = [];
            end

            % Atualizando o painel com os metadados do registro selecionado...
            relatedAnnotationTable   = search_Annotation_RelatedTable(app, showedHom);
            htmlSource = misc_SelectedHomPanel_InfoCreation(app, showedHom, relatedAnnotationTable);
            misc_SelectedHomPanel_InfoUpdate(app, 'search', htmlSource, showedHom);


            % Ajusta apenas o estilo de anotação do registro.
            search_Table_RemoveStyle(app, 'cell')
            search_Table_AnnotationIcon(app)

            % !! PONTO DE EVOLUÇÃO !!
        end


        %-----------------------------------------------------------------%
        function listOfColumns = search_Filtering_primaryTableColumns(app)
            selectedButton = app.search_PrimaryPanel.SelectedObject;
            listOfColumns  = selectedButton.Text;
        end


        %-----------------------------------------------------------------%
        function search_Filtering_primaryFilter(app, words2Search)
            d = layout.modalWindow(app.UIFigure, 'ccTools.ProgressDialog');

            % Rotina de inicialização dos objetos relacionados aos filtros 
            % secundários.
            set(findobj(app.search_SecundaryGrid, 'Type', 'uicheckbox'),  'Value', 0,  'Enable', 1)
            set(findobj(app.search_SecundaryGrid, 'Type', 'uieditfield'), 'Value', '', 'Enable', 0)
            set(findobj(app.search_SecundaryGrid, 'Type', 'uidropdown'),  'Items', {}, 'Enable', 0)

            % Filtragem primária.
            cacheColumns = search_Table_PrimaryColumnNames(app);
            listOfCacheColumns = cellfun(@(x) sprintf('_%s', x), cacheColumns, 'UniformOutput', false);
            
            primaryTempIndex   = [];
            for ii = 1:numel(words2Search)
                if app.config_SearchModeTokenSuggestion.Value
                    primaryTempIndex = [primaryTempIndex; find(any(strcmp(app.rawDataTable{:, listOfCacheColumns}, words2Search{ii}), 2))];
                
                else
                    primaryTempIndex = [primaryTempIndex; find(any(contains(app.rawDataTable{:, listOfCacheColumns}, words2Search{ii}), 2))];
                end
            end
            primaryTempIndex = unique(primaryTempIndex, 'stable');

            % Adicionando os registros que possuem o mesmo número de "Homologação", 
            % mas não contém as palavras pesquisadas. A primeira abordagem
            % (comentada) é muito mais rápida, mas se perde a ORDEM dos registros, 
            % infelizmente. Por isso, escolhida a abordagem com cellfun e
            % vertcat concatenados.
            primaryHom       = unique(app.rawDataTable(primaryTempIndex,:).("Homologação"), 'stable');
          
          % primaryIndex     = find(ismember(app.rawDataTable.("Homologação"), primaryHom));            
            primaryCellIndex = cellfun(@(x) find(strcmp(app.rawDataTable.("Homologação"), x)), primaryHom, 'UniformOutput', false);
            primaryIndex     = vertcat(primaryCellIndex{:});

            GUIColumns       = search_Table_ColumnNames(app);
            
            set(app.search_Table, 'Data',      app.rawDataTable(primaryIndex, GUIColumns), ...
                                  'UserData',  struct('primaryIndex', primaryIndex, 'secundaryIndex', primaryIndex, 'cacheColumns', {cacheColumns}))

            % Cria chart para a nuvem de palavras...
            if isempty(app.wordCloudChart)
                search_WordCloud_ChartCreation(app)
            end

            % Torna visível a tabela e outros elementos relacionados à tabela...
            search_Table_Visibility(app)
                        
            % Renderiza em tela o número de linhas, além de selecionar a primeira 
            % linha da tabela, caso a pesquisa retorne algo.
            misc_Table_NumberOfRows(app, 'search')
            search_Table_InitialSelection(app, true)

            % Aplica estilo na tabela e verifica se a tabela está visível...
            search_Table_AddStyle(app)

            delete(d)
        end


        %-----------------------------------------------------------------%
        function search_SuggestionPanel_InitialValues(app)
            set(app.search_Suggestions, Visible=0, Items={}, ItemsData=[])
        end


        %-----------------------------------------------------------------%
        function search_EntryPoint_InitialValue(app)
            set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
            app.search_entryPointImage.Enable = 0;
        end


        %-----------------------------------------------------------------%
        function search_EntryPoint_CheckIfNeedsUpdate(app)
            if ~strcmp(app.search_entryPoint.Value, app.previousSearch)
                waitfor(app.search_entryPoint, 'Value')
            end
        end


        %-----------------------------------------------------------------%
        function search_EntryPoint_Color(app, redFlag)
            if redFlag
                fontColor = [1,0,0];
            else
                fontColor = [0,0,0];
            end
            app.search_entryPoint.FontColor = fontColor;
            drawnow
        end


        %-----------------------------------------------------------------%
        function search_EntryPointImage_Status(app, value2Search)
            if numel(value2Search) < app.config_nMinCharacters.Value
                app.search_entryPointImage.Enable = 0;
                search_SuggestionPanel_InitialValues(app)
            else
                app.search_entryPointImage.Enable = 1;
            end
        end


        %-----------------------------------------------------------------%
        function search_Panel_Visibility(app)
            showedHom = app.search_ProductInfo.Tag;
            if ~isempty(showedHom) && app.search_Tab1Grid.RowHeight{6}
                app.search_AnnotationPanelAdd.Enable = 1;
            else
                app.search_AnnotationPanelAdd.Enable = 0;
            end
        end


        %-----------------------------------------------------------------%
        function search_Table_Visibility(app)
            if ~app.search_Table.Visible
                app.search_Table.Visible    = 1;
                app.search_Metadata.Visible = 1;                
                app.search_nRows.Visible    = 1;
            end
        end


        %-----------------------------------------------------------------%
        function cacheColumns = search_Table_PrimaryColumnNames(app)
            cacheColumns = strsplit(search_Filtering_primaryTableColumns(app), ' | ');
        end


        %-----------------------------------------------------------------%
        function [columnNames, columnWidth] = search_Table_ColumnNames(app)
            checkedNodes = app.config_SelectedTableColumns.CheckedNodes;
            staticColums = class.Constants.search_TableStaticColumns;
            columnNames  = unique([staticColums, arrayfun(@(x) x.Text, checkedNodes, 'UniformOutput', false)'], 'stable');

            allColumns   = class.Constants.search_TableColumnNames;
            widthColumns = class.Constants.search_TableColumnWidths;

            columnWidth  = {};
            for ii = 1:numel(columnNames)
                columnName      = columnNames{ii};
                columnIndex     = find(strcmp(allColumns, columnName), 1);
                
                columnWidth{ii} = widthColumns{columnIndex};
            end
        end


        %-----------------------------------------------------------------%
        function search_Table_InitialSelection(app, focusFlag)
            if isempty(app.search_Table.Data)
                app.search_Table.Selection = [];
            else
                app.search_Table.Selection = 1;
            end            
            search_Table_SelectionChanged(app)

            if focusFlag
                focus(app.search_Table)
            end
        end


        %-----------------------------------------------------------------%
        function search_Table_AddStyle(app)
            search_Table_RemoveStyle(app, 'all')

            % Row striping
            [~, ~, uniqueHomIndex] = unique(app.search_Table.Data.("Homologação"), 'stable');
            listOfRows             = find(~mod(uniqueHomIndex, 2));
            if ~isempty(listOfRows)
                addStyle(app.search_Table, class.Constants.configStyle1, 'row', listOfRows)
            end

            % Table primary columns background
            cacheColumns = app.search_Table.UserData.cacheColumns;
            addStyle(app.search_Table, class.Constants.configStyle2, 'column', cacheColumns)
            
            % Table annotation icon
            search_Table_AnnotationIcon(app)
            drawnow nocallbacks
        end


        %-----------------------------------------------------------------%
        function search_Table_RemoveStyle(app, styleType)
            switch styleType
                case 'all'
                    removeStyle(app.search_Table)

                otherwise
                styleTypeIndex  = find(strcmp(cellstr(app.search_Table.StyleConfigurations.Target), styleType));
                if ~isempty(styleTypeIndex)
                    removeStyle(app.search_Table, styleTypeIndex)
                end
            end
        end


        %-----------------------------------------------------------------%
        function search_Table_AnnotationIcon(app)
            % Posição da coluna "Homologação".
            homColumnIndex    = find(strcmp(app.search_Table.Data.Properties.VariableNames, 'Homologação'), 1);

            % Valores únicos de homologação e seus índices...
            [listOfHom, ...
             lisOfHomIndex]   = unique(app.search_Table.Data.("Homologação"), 'stable');
            
            % Identifica registros para os quais existe anotação registrada, 
            % aplicando o estilo.
            annotationLogical = ismember(listOfHom, unique(app.annotationTable.("Homologação")));
            annotationIndex   = lisOfHomIndex(annotationLogical);
            listOfCells       = [annotationIndex, repmat(homColumnIndex, numel(annotationIndex), 1)];

            if ~isempty(listOfCells)
                if app.search_PrimaryOption1.Value
                    s = class.Constants.configStyle3;
                else
                    s = class.Constants.configStyle4;
                end
                addStyle(app.search_Table, s, "cell", listOfCells)
            end
        end


        %-----------------------------------------------------------------%
        function search_WordCloud_ChartCreation(app)
            t = tiledlayout(app.search_WordCloudPanel, 1, 1, 'Padding', 'tight');
            app.wordCloudChart = wordcloud(t, search_WordCloud_EmptyTable(app), 'Word', 'Count',                  ...
                                           'MaxDisplayWords', str2double(app.config_WordCloudNumber.Value), ...
                                           'Title', '', 'Units', 'normalized', 'Position', [0,0,1,1]);
        end


        %-----------------------------------------------------------------%
        function emptyTable = search_WordCloud_EmptyTable(app)
            emptyTable = table('Size',          [0,2],                ...
                               'VariableTypes', {'string', 'double'}, ...
                               'VariableNames', {'Word', 'Count'});
        end


        %-----------------------------------------------------------------%
        function status = search_WordCloud_CheckCache(app, selectedHom, relatedTable)
            status = false;

            wordCloudLogical = strcmp(relatedTable.("Atributo"), 'WordCloud');
            relatedTable     = relatedTable(wordCloudLogical, :);

            if isempty(relatedTable) || any(wordCloudLogical) && ~strcmp(app.search_WordCloudPanel.Tag, selectedHom)
                status = true;
            end
        end


        %-----------------------------------------------------------------%
        function status = search_WordCloud_PlotUpdate(app, showedHom, wourdCloudRefreshTag, dialogProgressFlag)
            status = true;

            if dialogProgressFlag
                d = layout.modalWindow(app.UIFigure, 'ccTools.ProgressDialog');
            end

            if wourdCloudRefreshTag
                wordCloudIndex = [];
            else
                relatedAnnotationTable = search_Annotation_RelatedTable(app, showedHom);
                wordCloudIndex = find(strcmp(relatedAnnotationTable.("Atributo"), 'WordCloud'), 1);
            end

            if ~isempty(wordCloudIndex)
                wordCloudAnnotation = relatedAnnotationTable.("Valor"){wordCloudIndex};
                wordCloudTable      = fcn.getWordCloudFromCache(wordCloudAnnotation);

            else
                try
                    word2Search = search_WordCloud_Word2Search(app, showedHom);
                    nMaxWords   = str2double(app.config_WordCloudNumber.Value);

                    [wordCloudTable, wordCloudInfo] = fcn.getWordCloudFromWeb(word2Search, nMaxWords);
                    if ~isempty(wordCloudTable)
                        search_Annotation_Add2Cache(app, showedHom, 'WordCloud', wordCloudInfo, wourdCloudRefreshTag)
                    end

                catch ME
                    if exist('d', 'var')
                        delete(d)
                    end
                    layout.modalWindow(app.UIFigure, 'warning', ME.message);

                    status = false;
                    return
                end
            end

            if ~isempty(wordCloudTable)                    
                app.wordCloudChart.SourceTable = wordCloudTable;
                app.search_WordCloudPanel.Tag  = showedHom;
                drawnow
            end

            if exist('d', 'var')
                delete(d)
            end
        end


        %-----------------------------------------------------------------%
        function word2Search = search_WordCloud_Word2Search(app, showedHom)
            selectedRow = find(strcmp(app.search_Table.Data.("Homologação"), showedHom), 1);
            listOfWords = {char(app.search_Table.Data.("Modelo")(selectedRow)), ...
                           char(app.search_Table.Data.("Nome Comercial")(selectedRow))};

            switch app.config_WordCloudColumn.Value
                case 'Modelo';         idx1 = 1;
                case 'Nome Comercial'; idx1 = 2;
            end
            word2Search = listOfWords{idx1};

            if isempty(word2Search)
                idx2 = setdiff([1 2], idx1);
                word2Search = listOfWords{idx2};

                if isempty(word2Search)
                    error('Registro %s não possui cadastrado "Modelo" ou "Nome Comercial", inviabilizando consulta à internet.', showedHom)
                else
                    listOfColumns = {'Modelo', 'Nome Comercial'};
                    layout.modalWindow(app.UIFigure, 'warning', sprintf('O registro %s não possui cadastrado "%s". Dessa forma, consulta à internet foi realizada usando o seu "%s".', showedHom, listOfColumns{idx1}, listOfColumns{idx2}));
                end
            end
        end


        %-----------------------------------------------------------------%
        function report_SelectedHomPanel_InfoUpdate(app, selected2showedHom, selectedRow)
            if ~isempty(selected2showedHom)
                % Primeiro ajusta o painel com botões de rádio...
                if ~strcmp(selected2showedHom, '-1')
                    app.report_productSituation1.Value = 1; % HOMOLOGADO
                else
                    app.report_productSituation2.Value = 1; % NÃO HOMOLOGADO
                end
                set(app.report_productSituationPanel.Children, 'Enable', 0)

                % O título do painel e a imagem para confirmar alteração...
                app.report_EditableInfoLabel.Text      = 'EDIÇÃO COMPLEMENTAR À DA TABELA';
                app.report_EditOrAddButton.ImageSource = app.report_EditOrAddButton.UserData.iconOptions{1};

                % E depois as caixas de edição abaixo...
                idx = selectedRow;
                set(app.report_nHom, 'Enable', 0, 'Value', app.listOfProducts.("Homologação"){idx})
                app.report_Situation.Value = app.listOfProducts.("Situação"){idx};
                app.report_Violation.Value = app.listOfProducts.("Infração"){idx};
                app.report_Notes.Value     = app.listOfProducts.("Informações adicionais"){idx};

            else
                app.report_productSituation2.Value = 1;
                set(app.report_productSituationPanel.Children, 'Enable', 1)
                report_productSituationPanelSelectionChanged(app)

                app.report_EditableInfoLabel.Text      = 'INCLUSÃO';
                app.report_EditOrAddButton.ImageSource = app.report_EditOrAddButton.UserData.iconOptions{2};

                app.report_Situation.Value = 'Irregular';
                app.report_Violation.Value = '';
                app.report_Notes.Value     = '';
            end
        end


        %-----------------------------------------------------------------%
        function report_ListOfProductsAdd(app, srcMode, listOfHom2Add)
            for ii = 1:numel(listOfHom2Add)
                selectedHom2Add = listOfHom2Add{ii};                

                switch srcMode
                    case 'search'
                        if ~ismember(selectedHom2Add, app.listOfProducts.("Homologação"))
                            app.listOfProducts(end+1, [1:4,14:16]) = report_newRow2Add(app, selectedHom2Add);
                            app.listOfProducts = sortrows(app.listOfProducts, 'Homologação');
                        end

                    otherwise
                        legalSituation  = app.report_Situation.Value;
                        typeOfViolation = app.report_Violation.Value;
                        optionalNote    = strjoin(strtrim(app.report_Notes.Value), '\n');

                        if strcmp(srcMode, 'report - addRow')
                            switch selectedHom2Add
                                case '-1'
                                    newRow2Add = {'-1', '-1', '', '', legalSituation, typeOfViolation, optionalNote};

                                otherwise
                                    newRow2Add = report_newRow2Add(app, selectedHom2Add);
                                    newRow2Add(5:7) = {legalSituation, typeOfViolation, optionalNote};
                            end

                            idx = height(app.listOfProducts) + 1;                            
                            app.listOfProducts(idx, [1:4,14:16]) = newRow2Add;
                            app.listOfProducts = sortrows(app.listOfProducts, 'Homologação');

                        else
                            idx = find(strcmp(app.listOfProducts.("Homologação"), selectedHom2Add), 1);
                            app.listOfProducts(idx, 14:16) = {legalSituation, typeOfViolation, optionalNote};
                        end
                end
            end

            % Atualizando a lista de produtos homologados sob análise (do 
            % modo SEARCH).
            homLogical = ~strcmp(app.listOfProducts.("Homologação"), '-1');
            app.search_ListOfProducts.Items = app.listOfProducts.("Homologação")(homLogical);
            
            % Atualizando a tabela e o número de linhas (do modo REPORT), nessa 
            % ordem. E depois forçando uma atualização dos paineis.
            app.report_Table.Data = app.listOfProducts(:,1:13);
            misc_Table_NumberOfRows(app, 'report')

            report_TableSelectionChanged(app)
        end


        %-----------------------------------------------------------------%
        function newRow2Add = report_newRow2Add(app, selectedHom2Add)
            selectedHomRawTableIndex = find(strcmp(app.rawDataTable.("Homologação"), selectedHom2Add));
            relatedSCHTable = app.rawDataTable(selectedHomRawTableIndex, :);
            
            Fabricator   = upper(char(relatedSCHTable.("Fabricante")(1)));
            typeList     = unique(cellstr(relatedSCHTable.("Tipo")));                    
            
            modelValue   = char(relatedSCHTable.("Modelo")(1));
            modelList    = unique([relatedSCHTable.("Modelo"); relatedSCHTable.("Nome Comercial")]);
            modelList(cellfun(@(x) isempty(x), modelList)) = [];
            
            optionalNote = sprintf('TIPO: %s\nMODELO: %s', ccTools.fcn.FormatString(typeList), ccTools.fcn.FormatString(modelList));
            
            newRow2Add   = {selectedHom2Add, '-1', Fabricator, modelValue, 'Irregular', '', optionalNote};
        end


        %-----------------------------------------------------------------%
        function config_DataHubWarningLamp(app)
            DataHub_GET  = app.General.fileFolder.DataHub_GET;
            DataHub_POST = app.General.fileFolder.DataHub_POST;

            if isfolder(DataHub_GET) && isfolder(DataHub_POST)
                app.DataHubLamp.Visible = 0;
            else
                app.DataHubLamp.Visible = 1;
            end
        end


        %-----------------------------------------------------------------%
        % MISCELÂNEAS
        %-----------------------------------------------------------------%

        %-----------------------------------------------------------------%
        % Aqui ficarão as funções relacionadas à GUI que são chamadas por 
        % módulos diferentes do app.
        %
        % Por exemplo, as funções relacionadas às tabelas dos modos SEARCH
        % e REPORT. São operações logicalmente idênticas, mas com componentes
        % diferentess.
        %
        % Em relação à nomenclatura de linha(s) selecionada(s) em uma das
        % tabelas, tem-se:
        % - selectedHom.......: Lista de homologações selecionados (cell array).
        % - showedHom.........: Homologação apresentada em painel de metadados.
        % - selected2showedHom: Em processo de atualização do painel de metadados.
        %                       De selectedHom{1} para showedHom...
        %-----------------------------------------------------------------%
        function misc_Panel_menuButtonPushed(app, operationMode, tabIndex)
            NN = eval(sprintf('numel(app.%s_ControlMenu.ColumnWidth)', operationMode));

            for ii = 1:NN
                switch ii
                    case tabIndex
                        eval(sprintf('app.%s_menuBtn%dGrid.ColumnWidth{2} = "1x";', operationMode, ii))
                    otherwise
                        eval(sprintf('app.%s_menuBtn%dGrid.ColumnWidth{2} = 0;',    operationMode, ii))
                end
            end

            columnWidth           = repmat({22}, 1, NN);
            columnWidth(tabIndex) = {'1x'};

            eval(sprintf('app.%s_ControlMenu.ColumnWidth     = columnWidth;', operationMode))
            eval(sprintf('app.%s_menuUnderline.Layout.Column = tabIndex;',    operationMode))
            eval(sprintf('app.%s_ControlTabGroup.SelectedTab = app.%s_ControlTabGroup.Children(tabIndex);', operationMode, operationMode))

            focus(app.jsBackDoor)
        end


        %-----------------------------------------------------------------%
        function [selectedHom, showedHom, selectedRow] = misc_Table_SelectedRow(app, operationMode)
            switch operationMode
                case 'search'
                    table2Search = app.search_Table;
                    panel2Search = app.search_ProductInfo;
                case 'report'
                    table2Search = app.report_Table;
                    panel2Search = app.report_ProductInfo;
            end

            selectedRow = table2Search.Selection;
            if ~isempty(selectedRow)
                selectedHom = unique(table2Search.Data.("Homologação")(selectedRow), 'stable');
            else
                selectedHom = {};
            end        
            showedHom = panel2Search.Tag;
        end


        %-----------------------------------------------------------------%
        function misc_Table_NumberOfRows(app, operationMode)
            switch operationMode
                case 'search'
                    nRows = height(app.search_Table.Data);
                    app.search_nRows.Text = sprintf('# %d ', nRows);
                case 'report'
                    nRows = height(app.report_Table.Data);
                    app.report_nRows.Text = sprintf('# %d ', nRows);
            end
        end


        %-----------------------------------------------------------------%
        function htmlSource = misc_SelectedHomPanel_InfoCreation(app, selected2showedHom, relatedAnnotationTable)
            if isempty(selected2showedHom)
                htmlSource = 'Warning.html';

            elseif strcmp(selected2showedHom, '-1')
                selectedRow = app.report_Table.Selection(1);
                htmlSource = fcn.htmlCode_rowTableInfo('ProdutoNãoHomologado', app.listOfProducts(selectedRow, :));

            else
                selectedHomRawTableIndex = find(strcmp(app.rawDataTable.("Homologação"), selected2showedHom));
                htmlSource = fcn.htmlCode_rowTableInfo('ProdutoHomologado', app.rawDataTable(selectedHomRawTableIndex, :), relatedAnnotationTable);
            end
        end


        %-----------------------------------------------------------------%
        function misc_SelectedHomPanel_InfoUpdate(app, operationMode, htmlSource, selected2showedHom)
            switch operationMode
                case 'search'
                    set(app.search_ProductInfo, 'HTMLSource', htmlSource, 'Tag', selected2showedHom)
                case 'report'
                    set(app.report_ProductInfo, 'HTMLSource', htmlSource, 'Tag', selected2showedHom)
            end
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                % PATH SEARCH
                app.rootFolder = fileparts(mfilename('fullpath'));

                if isdeployed
                    [~, result]     = system('path');

                    executionFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    executionExt    = ccTools.fcn.OperationSystem('executionExt');

                    if isfile(fullfile(executionFolder, sprintf('%s.%s', class.Constants.appName, executionExt)))
                        app.executionMode = 'desktopStandaloneApp';
                        app.rootFolder    = executionFolder;
                    else
                        app.executionMode = 'webApp';
                    end
                else
                    app.executionMode = 'MATLABEnvironment';
                end
                
                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')
                warning('off', 'MATLAB:table:RowsAddedExistingVars')
                warning('off', 'MATLAB:colon:operandsNotRealScalar')

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % TRECHO TEMPORÁRIO RELACIONADO À GUI. REMOVER NA COMPILAÇÃO! %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
                
                app.search_mainGrid.ColumnWidth{1}  = 0;
                app.search_Tab1Grid.RowHeight(4:14) = repmat({0}, 1, 11);
                
                app.report_mainGrid.ColumnWidth{1}  = 0;
                app.report_Tab1Grid.RowHeight(4:7)  = {0,0,0,0};

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % TRECHO TEMPORÁRIO RELACIONADO À GUI. REMOVER NA COMPILAÇÃO! %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % STARTUP
                app.home_Title.Text = sprintf('SCH<font style="font-size:12px;"> v. %s</font>', class.Constants.appVersion);

                layout.winPosition(app)
                jsBackDoor_Initialization(app)
                startup_timerCreation(app)

            catch ME
                layout.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % BASE DE ANOTAÇÕES
            writeFile.Annotation(app.rootFolder, app.General.fileFolder.DataHub_POST, app.annotationTable, app.annotationBackupFlag);
    
            % TIMER
            h = timerfindall;
            stop(h); delete(h); clear h

            delete(app)
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            hitObject = struct(event).HitObject;
            switch hitObject
                case app.search_entryPoint
                    switch app.search_entryPoint.Value
                        case 'O que você quer pesquisar?'
                            set(app.search_entryPoint, 'FontColor', [0,0,0], 'Value', '')

                        otherwise
                            if app.config_SearchModeTokenSuggestion.Value 
                                if numel(app.search_entryPoint.Value) >= app.config_nMinCharacters.Value
                                    app.search_Suggestions.Visible = 1;
                                end
                            end
                    end

                case app.search_Suggestions
                    if isempty(app.search_Suggestions.Value)
                        waitfor(app.search_Suggestions, 'Value')
                    end

                    jsBackDoor_keyDownListener(app, struct('HTMLEventData', 'app.search_Suggestions', 'HTMLEventName', 'Enter'))

                otherwise
                    set(app.search_Suggestions, Visible=0, Value={})
                    if isempty(app.search_entryPoint.Value)
                        search_EntryPoint_InitialValue(app)
                    end                    
            end

        end

        % Value changed function: menu_Button1, menu_Button2, 
        % ...and 2 other components
        function menu_mainButtonPushed(app, event)
            
            clickedButton = event.Source;

            if clickedButton.Value                
                tabIndex      = clickedButton.UserData.tabGroup;
              % tabIndex      = str2double(clickedButton.Tag);

                nonClickedButtons = findobj(app.menu_Grid, 'Type', 'uistatebutton', '-not', 'Tag', clickedButton.Tag);
                arrayfun(@(x) set(x, 'Value', 0, 'Icon', x.UserData.iconOptions{1}), nonClickedButtons)
                set(clickedButton, 'Icon', clickedButton.UserData.iconOptions{2})
                
                app.TabGroup.SelectedTab = app.TabGroup.Children(tabIndex);
                drawnow

                % A customização somente pode tem efeito se os componentes
                % já tiverem sido renderizados no HTML. Por essa razão, inclui-se 
                % um drawnow após a mudança da aba.
                menu_LayoutControl(app, tabIndex)
                jsBackDoor_Customizations(app, tabIndex)

            else
                event.Source.Value = 1;
            end

        end

        % Image clicked function: AppInfo, FigurePosition
        function menu_auxiliarButtonPushed(app, event)
            
            switch event.Source
                case app.FigurePosition
                    app.UIFigure.Position(3:4) = class.Constants.windowSize;
                    layout.winPosition(app)

                case app.AppInfo
                    if isempty(app.AppInfo.Tag)
                        app.AppInfo.Tag = fcn.htmlCode_appInfo(app.General, app.rootFolder, app.executionMode, app.rawDataTable, app.releasedData, app.cacheData);
                    end

                    msgInfo = app.AppInfo.Tag;
                    layout.modalWindow(app.UIFigure, 'info', msgInfo);
            end

            focus(app.jsBackDoor)

        end

        % Value changing function: search_entryPoint
        function search_EntryPoint_ValueChanging(app, event)

            search_SuggestionAlgorithm(app, event.Value, true)

        end

        % Image clicked function: search_entryPointImage
        function search_EntryPoint_ImageClicked(app, event)
            
            listOfColumns = search_Filtering_primaryTableColumns(app);
            selectedMode  = fcn.extractHTMLText(app.config_SearchModePanel.SelectedObject.Text, 'P');            

            if app.config_SearchModeTokenSuggestion.Value
                value2Search = fcn.PreProcessedData(app.search_entryPoint.Value);
                words2Search = app.search_Suggestions.Items;
                
                if ~isempty(words2Search)
                    nWords2Search  = numel(words2Search);
                    nWordsContains = sum(contains(words2Search, value2Search));
                    
                    % Criando uma amostra da lista para mostrar em tela, mas 
                    % limitando a lista em 400 caracteres...
                    sizeOfWords    = cellfun(@(x) numel(x), words2Search);
                    
                    for ii = 0:nWords2Search-1
                        if sum(sizeOfWords(1:nWords2Search-ii)) < 256
                            listOfWords = words2Search(1:nWords2Search-ii);
                            break
                        end
                    end
    
                    % Inserindo o texto "e similiares" caso exista alguma
                    % palavra-chave que não contenha o termo a procurar.
                    if nWordsContains < nWords2Search
                        searchNote = ' e similares';
                    else
                        searchNote = '';
                    end
        
                    app.search_Metadata.Text = sprintf('Exibindo resultados para "<b>%s</b>"%s\n<p style="color: #808080; font-size:10px; text-align:justify;">Parâmetros de pesquisa: %s</p>', ...
                                                       app.search_entryPoint.Value, searchNote, jsonencode(struct('Modo', selectedMode, 'Coluna', {listOfColumns}, 'Qtd', sprintf('%d de %d', nWordsContains, nWords2Search), 'Amostra', {listOfWords})));
                    search_Filtering_primaryFilter(app, words2Search)
                end

            else
                words2Search = fcn.PreProcessedData(strsplit(app.search_entryPoint.Value, ','));

                if ~isempty(words2Search)
                    app.search_Metadata.Text = sprintf('Exibindo resultados para %s\n<p style="color: #808080; font-size:10px;">Parâmetros de pesquisa: %s</p>', ...
                                                       strjoin("""<b>" + string(words2Search) + "</b>""", ', '), jsonencode(struct('Modo', selectedMode, 'Coluna', {listOfColumns})));
                    search_Filtering_primaryFilter(app, words2Search)
                end
            end

        end

        % Selection changed function: search_Table
        function search_Table_SelectionChanged(app, event)
            
            [selectedHom, showedHom] = misc_Table_SelectedRow(app, 'search');

            if ~isempty(selectedHom)
                if ~ismember(showedHom, selectedHom)
                    % Escolhe o primeiro registro da lista de homologações selecionadas
                    % em tabela.
                    selected2showedHom     = selectedHom{1};
                    relatedAnnotationTable = search_Annotation_RelatedTable(app, selected2showedHom);

                    htmlSource = misc_SelectedHomPanel_InfoCreation(app, selected2showedHom, relatedAnnotationTable);
                    misc_SelectedHomPanel_InfoUpdate(app, 'search', htmlSource, selected2showedHom)
    
                    % Apresenta a nuvem de palavras apenas se visível...
                    if app.search_Tab1Grid.RowHeight{8}
                        if search_WordCloud_CheckCache(app, selected2showedHom, relatedAnnotationTable)
                            if exist('event', 'var')
                                dialogProgressFlag = true;
                            else
                                dialogProgressFlag = false;
                            end
    
                            search_WordCloud_PlotUpdate(app, selected2showedHom, false, dialogProgressFlag);
                        end
                    end
    
                    app.search_ToolbarAnnotation.Enable     = 1;
                    app.search_ToolbarWordCloud.Enable      = 1;
                    app.search_ToolbarListOfProducts.Enable = 1;    
                    app.search_WordCloudRefresh.Enable      = 1;                    
                end

            else
                htmlSource = misc_SelectedHomPanel_InfoCreation(app, '', []);
                misc_SelectedHomPanel_InfoUpdate(app, 'search', htmlSource, '')

                if isa(app.wordCloudChart, 'matlab.graphics.chart.WordCloudChart') && ~isempty(app.search_WordCloudPanel.Tag)
                    app.wordCloudChart.SourceTable      = search_WordCloud_EmptyTable(app);
                    app.search_WordCloudPanel.Tag       = '';
                end

                app.search_ToolbarAnnotation.Enable     = 0;
                app.search_ToolbarWordCloud.Enable      = 0;
                app.search_ToolbarListOfProducts.Enable = 0;
                app.search_WordCloudRefresh.Enable      = 0;                
            end

            search_Panel_Visibility(app)
            
        end

        % Selection changed function: search_PrimaryPanel
        function search_Filtering_primaryTableColumnsChanged(app, event)
            
            app.previousSearch = '';

            search_EntryPoint_InitialValue(app)
            search_SuggestionPanel_InitialValues(app)

        end

        % Value changed function: search_ApplicantCheckBox, 
        % ...and 7 other components
        function search_Filtering_secundaryFilterCheckBoxClicked(app, event)
            
            columnName   = event.Source.Text;
            editfieldObj = findobj(app.search_SecundaryGrid, 'Tag', columnName);

            switch editfieldObj.Type
                case 'uieditfield'
                    if event.Source.Value
                        set(editfieldObj, 'Enable', 1, 'Value', '')
    
                    else
                        set(editfieldObj, 'Enable', 0, 'Value', '')
                        search_Filtering_secundaryFilterValueChanging(app)
                    end

                case 'uidropdown'
                    if event.Source.Value
                        primaryIndex = app.search_Table.UserData.primaryIndex;
                        set(editfieldObj, 'Enable', 1, 'Items', [{''}; cellstr(unique(app.rawDataTable{primaryIndex, columnName}))])
    
                    else
                        set(editfieldObj, 'Enable', 0, 'Items', {})
                        search_Filtering_secundaryFilterValueChanging(app)
                    end
            end

        end

        % Callback function: search_Applicant, search_CNPJ, 
        % ...and 6 other components
        function search_Filtering_secundaryFilterValueChanging(app, event)
            
            primaryIndex = app.search_Table.UserData.primaryIndex;

            checkboxList = findobj(app.search_SecundaryGrid, 'Type', 'uicheckbox', 'Value', 1);
            GUIColumns   = search_Table_ColumnNames(app);

            if ~isempty(checkboxList)
                columnNames   = arrayfun(@(x) x.Text, checkboxList, 'UniformOutput', false);
                logicalMatrix = zeros(numel(primaryIndex), numel(columnNames), 'logical');
    
                for ii = 1:numel(columnNames)
                    columnName      = columnNames{ii};
                    cacheColumnName = suggestion.getCacheColumnName(app.rawDataTable.Properties.VariableNames, columnName);
                    
                    if ~strcmp(columnName, cacheColumnName)
                        if exist('event', 'var') && strcmp(event.Source.Tag, columnName)
                            value2Search = fcn.PreProcessedData(event.Value);
                        else
                            editfieldObj = findobj(app.search_SecundaryGrid, 'Tag', columnName);
                            value2Search = fcn.PreProcessedData(editfieldObj.Value);                        
                        end
        
                        logicalMatrix(:,ii) = contains(app.rawDataTable{primaryIndex, cacheColumnName}, value2Search, 'IgnoreCase', true);

                    else
                        if exist('event', 'var') && strcmp(event.Source.Tag, columnName)
                            value2Search = event.Value;
                        else
                            editfieldObj = findobj(app.search_SecundaryGrid, 'Tag', columnName);
                            value2Search = editfieldObj.Value;                        
                        end

                        if isempty(value2Search)
                            logicalMatrix(:,ii) = true;
                        else
                            if iscategorical(app.rawDataTable{primaryIndex, cacheColumnName})
                                logicalMatrix(:,ii) = strcmpi(cellstr(app.rawDataTable{primaryIndex, cacheColumnName}), value2Search);
                            else
                                logicalMatrix(:,ii) = contains(app.rawDataTable{primaryIndex, cacheColumnName}, value2Search, 'IgnoreCase', true);
                            end
                        end
                    end
                end
                logicalArray   = all(logicalMatrix, 2);
                secundaryIndex = primaryIndex(logicalArray);
                    
                app.search_Table.Data = app.rawDataTable(secundaryIndex, GUIColumns);

            else
                secundaryIndex = primaryIndex;
                app.search_Table.Data = app.rawDataTable(primaryIndex, GUIColumns);
            end
            app.search_Table.UserData.secundaryIndex = secundaryIndex;

            % O foco muda para a tabela, caso tenha sido selecionado uma
            % lista suspensa. Em sendo uma caixa de texto, o foco permanece
            % na caixa de texto.
            focusFlag = false;
            if ~exist('event', 'var') || strcmp(event.Source.Type, 'uidropdown')
                focusFlag = true;
            end

            % Renderiza em tela o número de linhas, além de selecionar a primeira 
            % linha da tabela, caso a pesquisa retorne algo.
            misc_Table_NumberOfRows(app, 'search')
            search_Table_InitialSelection(app, focusFlag)

            % Aplica estilo na tabela...
            search_Table_AddStyle(app)

        end

        % Image clicked function: search_ToolbarAnnotation, 
        % ...and 2 other components
        function search_Panel_ToolbarButtonClicked(app, event)
            
            switch event.Source
                case app.search_ToolbarAnnotation
                    if app.search_Tab1Grid.RowHeight{6}
                        app.search_ToolbarAnnotation.ImageSource = app.search_ToolbarAnnotation.UserData.iconOptions{1};
                        app.search_Tab1Grid.RowHeight(6:7)       = {0,0};
                        
                        if ~app.search_Tab1Grid.RowHeight{8}
                            app.search_Tab1Grid.RowHeight(4:5)   = {0,0};
                        end

                    else
                        app.search_ToolbarAnnotation.ImageSource = app.search_ToolbarAnnotation.UserData.iconOptions{2};
                        app.search_Tab1Grid.RowHeight(4:7)       = {5,22,5,148};
                    end
                    search_Panel_Visibility(app)

                case app.search_ToolbarWordCloud
                    if app.search_Tab1Grid.RowHeight{8}
                        app.search_ToolbarWordCloud.ImageSource   = app.search_ToolbarWordCloud.UserData.iconOptions{1};
                        app.search_Tab1Grid.RowHeight(8:10)       = {0,0,0};

                        if ~app.search_Tab1Grid.RowHeight{6}
                            app.search_Tab1Grid.RowHeight(4:5)    = {0,0};
                        end

                    else
                        app.search_ToolbarWordCloud.ImageSource   = app.search_ToolbarWordCloud.UserData.iconOptions{2};
                        app.search_Tab1Grid.RowHeight([4:5,8:10]) = {5,22,5,121,22};

                        showedHom              = app.search_ProductInfo.Tag;
                        relatedAnnotationTable = search_Annotation_RelatedTable(app, showedHom);

                        if search_WordCloud_CheckCache(app, showedHom, relatedAnnotationTable)
                            search_WordCloud_PlotUpdate(app, showedHom, false, true);
                        end
                    end

                case app.search_ToolbarListOfProducts
                    if app.search_Tab1Grid.RowHeight{11}
                        app.search_ToolbarListOfProducts.ImageSource = app.search_ToolbarListOfProducts.UserData.iconOptions{1};
                        app.search_Tab1Grid.RowHeight(11:14)         = {0,0,0,0};

                    else
                        app.search_ToolbarListOfProducts.ImageSource = app.search_ToolbarListOfProducts.UserData.iconOptions{2};
                        app.search_Tab1Grid.RowHeight(11:14)         = {5,22,5,148};
                    end
            end

            focus(app.jsBackDoor)

        end

        % Image clicked function: search_AnnotationPanelAdd
        function search_Annotation_AddImageClicked(app, event)
            
            % O valor de um objeto "uitextarea" será sempre um cellstr.
            % O arranjo a seguir elimina linhas vazias, concatenando as
            % outras.
            app.search_AnnotationValue.Value = strtrim(app.search_AnnotationValue.Value);
            app.search_AnnotationValue.Value(cellfun(@(x) isempty(x), app.search_AnnotationValue.Value)) = [];
            app.search_AnnotationValue.Value = strjoin(app.search_AnnotationValue.Value, ' ');

            showedHom      = app.search_ProductInfo.Tag;
            
            attributeName  = app.search_AnnotationAttribute.Value;
            attributeValue = char(app.search_AnnotationValue.Value);

            if ~isempty(attributeValue)
                search_Annotation_Add2Cache(app, showedHom, attributeName, attributeValue, false)

            else
                layout.modalWindow(app.UIFigure, 'warning', 'Deve ser informado o valor do atributo.');
            end

        end

        % Image clicked function: search_WordCloudRefresh
        function search_WordCloud_RefreshImageClicked(app, event)
            
            % Primeiro tenta atualizar a nuvem de palavras...
            showedHom = app.search_ProductInfo.Tag;
            search_WordCloud_PlotUpdate(app, showedHom, true, true);

            focus(app.jsBackDoor)

        end

        % Image clicked function: search_ListOfProductsAdd
        function search_Report_ListOfProductsAddImageClicked(app, event)
            
            [selectedHom, showedHom] = misc_Table_SelectedRow(app, 'search');
            
            if isempty(selectedHom)
                msgWarning = 'Selecione ao menos um registro na tabela.';
                layout.modalWindow(app.UIFigure, "warning", msgWarning);
                return

            else
                if ~isequal(selectedHom, {showedHom})
                    msgQuestion = sprintf(['<font style="font-size:12px;">Homologação apresentada no <b>PAINEL</b> à esquerda:\n• %s\n\n' ...
                                           'Homologações selecionadas em <b>TABELA</b>:\n%s\n\n'                                          ...
                                           'Quais os registros devem ser adicionados à lista de produtos sob análise?</font>'], showedHom, strjoin("• " + string(selectedHom), '\n'));
                    
                    selection   = uiconfirm(app.UIFigure, msgQuestion, '', 'Interpreter', 'html',                                                      ...
                                                                           'Options', {' PAINEL ', ' TABELA ', 'CANCELAR'}, ...
                                                                           'DefaultOption', 1, 'CancelOption', 3, 'Icon', 'question');
        
                    switch selection
                        case ' PAINEL '
                            listOfHom2Add = {showedHom};
                        case ' TABELA '
                            listOfHom2Add = selectedHom;
                        case 'CANCELAR'
                            return
                    end

                else
                    listOfHom2Add = selectedHom;
                end

                % Certificando que não será incluído um registro que já
                % consta em base...
                listOfHom2Add = setdiff(listOfHom2Add, app.listOfProducts.("Homologação"));
                report_ListOfProductsAdd(app, 'search', listOfHom2Add)
            end

            focus(app.jsBackDoor)

        end

        % Image clicked function: report_ToolbarEditOrAdd
        function report_ToolbarEditOrAddImageClicked(app, event)
            
            if app.report_Tab1Grid.RowHeight{4}
                app.report_ToolbarEditOrAdd.ImageSource = app.report_ToolbarEditOrAdd.UserData.iconOptions{1};
                app.report_Tab1Grid.RowHeight(4:7)      = {0,0,0,0};

            else
                app.report_ToolbarEditOrAdd.ImageSource = app.report_ToolbarEditOrAdd.UserData.iconOptions{2};
                app.report_Tab1Grid.RowHeight(4:7)      = {5,22,5,260};
            end

            focus(app.jsBackDoor)

        end

        % Selection changed function: report_Table
        function report_TableSelectionChanged(app, event)
            
            [selectedHom, showedHom, selectedRow] = misc_Table_SelectedRow(app, 'report');

            if ~isempty(selectedHom)
                if ~ismember(showedHom, selectedHom)
                    % Escolhe o primeiro registro da lista de homologações selecionadas
                    % em tabela.
                    selected2showedHom     = selectedHom{1};
                    relatedAnnotationTable = search_Annotation_RelatedTable(app, selected2showedHom);

                    htmlSource = misc_SelectedHomPanel_InfoCreation(app, selected2showedHom, relatedAnnotationTable);
                    misc_SelectedHomPanel_InfoUpdate(app, 'report', htmlSource, selected2showedHom)
                    report_SelectedHomPanel_InfoUpdate(app, selected2showedHom, selectedRow(1))
                end                

            else
                htmlSource = misc_SelectedHomPanel_InfoCreation(app, '', []);
                misc_SelectedHomPanel_InfoUpdate(app, 'report', htmlSource, '')
                report_SelectedHomPanel_InfoUpdate(app, '', [])
            end

        end

        % Cell edit callback: report_Table
        function report_TableCellEdit(app, event)
            
            % Grosseria! :)
            app.listOfProducts(:, 1:13) = app.report_Table.Data;

            % Atualizando o painel de metadados do registro selecionado...
            [~, showedHom] = misc_Table_SelectedRow(app, 'report');
            if strcmp(showedHom, '-1')    
                htmlSource = misc_SelectedHomPanel_InfoCreation(app, showedHom, []);
                misc_SelectedHomPanel_InfoUpdate(app, 'report', htmlSource, showedHom)
            end

        end

        % Selection changed function: report_productSituationPanel
        function report_productSituationPanelSelectionChanged(app, event)
            
            selectedButton = app.report_productSituationPanel.SelectedObject;
            
            switch selectedButton
                case app.report_productSituation1 % HOMOLOGADO
                    set(app.report_nHom, 'Enable', 1, 'Value', '')

                case app.report_productSituation2 % NÃO HOMOLOGADO
                    set(app.report_nHom, 'Enable', 0, 'Value', '-1')
            end
            
        end

        % Image clicked function: report_EditOrAddButton
        function report_EditOrAddButtonImageClicked(app, event)
            
            [~, showedHom] = misc_Table_SelectedRow(app, 'report');
            selectedHom = app.report_nHom.Value;

            if isempty(showedHom)
                srcMode = 'report - addRow';

                if ~strcmp(selectedHom, '-1')
                    if ismember(selectedHom, app.listOfProducts.("Homologação"))
                        layout.modalWindow(app.UIFigure, 'warning', sprintf('O número de homologação "%s" já foi incluído à lista de produtos sob análise.', selectedHom));
                        return
    
                    elseif ~ismember(selectedHom, app.rawDataTable.("Homologação"))
                        layout.modalWindow(app.UIFigure, 'warning', sprintf('O número de homologação "%s" não foi encontrado na base de dados. O formato esperado para esse campo é XXXXX-XX-XXXXX.', selectedHom));
                        return
                    end
                end      

            else
                srcMode = 'report - edit';
            end
      
            report_ListOfProductsAdd(app, srcMode, {selectedHom})

            focus(app.jsBackDoor)

        end

        % Image clicked function: report_ReportPreview
        function report_ReportPreviewImageClicked(app, event)
            
        end

        % Image clicked function: config_Folder_DataHubGETButton, 
        % ...and 2 other components
        function config_getFolder(app, event)
            
            selectedFolder = uigetdir;
            figure(app.UIFigure)

            if selectedFolder
                switch event.Source
                    case app.config_Folder_DataHubGETButton
                        app.config_Folder_DataHubGET.Value  = selectedFolder;
                        app.General.fileFolder.DataHub_GET  = selectedFolder;
                        config_DataHubWarningLamp(app)

                    case app.config_Folder_DataHubPOSTButton
                        app.config_Folder_DataHubPOST.Value = selectedFolder;
                        app.General.fileFolder.DataHub_POST = selectedFolder;
                        config_DataHubWarningLamp(app)

                    case app.config_Folder_UserPathButton
                        set(app.config_Folder_UserPath, 'Items', unique([app.config_Folder_UserPath.Items, selectedFolder]), ...
                                                        'Value', selectedFolder)
                        app.General.fileFolder.userPath     = selectedFolder;
                end

                writeFile.GeneralSettings(app.General, app.rootFolder)
            end

        end

        % Selection changed function: config_SearchModePanel
        function config_SearchModeChanged(app, event)
            
            selectedButton = app.config_SearchModePanel.SelectedObject;
            switch selectedButton
                case app.config_SearchModeTokenSuggestion
                    app.search_Document.ColumnWidth = {400, '1x'};

                case app.config_SearchModeListOfWords
                    app.search_Document.ColumnWidth = {'1x', 0};

                    app.previousSearch   = '';
                    app.previousItemData = 0;
                    
                    set(app.search_Suggestions, Visible=0, Items={}, ItemsData=[])
            end

            search_EntryPoint_InitialValue(app)
            
        end

        % Value changed function: config_WordCloudNumber
        function config_NumberOfWordsCloudValueChanged(app, event)
            
            if ~isempty(app.wordCloudChart)
                app.wordCloudChart.MaxDisplayWords = str2double(app.config_WordCloudNumber.Value);
            end

        end

        % Callback function: config_SelectedTableColumns
        function config_ListOfColumns2ShowChanged(app, event)
            
            checkedNodes   = app.config_SelectedTableColumns.CheckedNodes;
            checkedColumns = arrayfun(@(x) x.Text, checkedNodes, 'UniformOutput', false);

            staticColumns  = class.Constants.search_TableStaticColumns;
            staticColumns(ismember(staticColumns, checkedColumns)) = [];
            
            if ~isempty(staticColumns)
                for ii = 1:numel(staticColumns)
                    staticColumnName = staticColumns{ii};
                    staticTreeNodes  = findobj(app.config_SelectedTableColumns, 'Text', staticColumnName);
                    
                    app.config_SelectedTableColumns.CheckedNodes(end+1) = staticTreeNodes;
                end
            end
            [columnNames, columnWidth] = search_Table_ColumnNames(app);
            set(app.search_Table, 'ColumnName', upper(columnNames), 'ColumnWidth', columnWidth)

            if ~isempty(app.search_Table.Data)
                if (numel(columnNames) ~= width(app.search_Table.Data)) || any(~ismember(app.search_Table.ColumnName, upper(columnNames)))
                    primaryIndex = app.search_Table.UserData.primaryIndex;    
                    app.search_Table.Data = app.rawDataTable(primaryIndex, columnNames);
                end
            end
            
        end

        % Image clicked function: report_menuBtn1Icon, 
        % ...and 3 other components
        function misc_Panel_TabControlButtonPushed(app, event)
            
            switch event.Source
                case {app.search_menuBtn1Icon, app.search_menuBtn2Icon}
                    operationMode = 'search';
                case {app.report_menuBtn1Icon, app.report_menuBtn2Icon}
                    operationMode = 'report';
            end
            idx = str2double(event.Source.Tag);
            
            misc_Panel_menuButtonPushed(app, operationMode, idx)

        end

        % Image clicked function: report_PanelVisibility, 
        % ...and 1 other component
        function misc_Panel_VisibilityImageClicked(app, event)
            
            switch event.Source
                case app.search_PanelVisibility
                    if app.search_mainGrid.ColumnWidth{1}
                        app.search_PanelVisibility.ImageSource = 'ArrowRight_32White.png';
                        app.search_mainGrid.ColumnWidth{1}     = 0;        
                    else
                        app.search_PanelVisibility.ImageSource = 'ArrowLeft_32White.png';
                        app.search_mainGrid.ColumnWidth{1}     = 330;
                    end

                case app.report_PanelVisibility
                    if app.report_mainGrid.ColumnWidth{1}
                        app.report_PanelVisibility.ImageSource = 'ArrowRight_32White.png';
                        app.report_mainGrid.ColumnWidth{1}     = 0;        
                    else
                        app.report_PanelVisibility.ImageSource = 'ArrowLeft_32White.png';
                        app.report_mainGrid.ColumnWidth{1}     = 330;
                    end
            end

            focus(app.jsBackDoor)

        end

        % Menu selected function: ContextMenu_DeleteFcn
        function ContextMenu_DeleteFcnSelected(app, event)
            
            selectedTableIndex = [];
            selectedProduct    = {};

            switch event.ContextObject
                case app.search_ListOfProducts
                    selectedProduct = app.search_ListOfProducts.Value;

                    if ~isempty(selectedProduct)
                        selectedTableIndex = find(ismember(app.report_Table.Data.("Homologação"), selectedProduct));
                    end

                case app.report_Table
                    selectedTableIndex = app.report_Table.Selection;

                    if ~isempty(selectedTableIndex)
                        idx = selectedTableIndex(~strcmp(app.report_Table.Data.("Homologação")(selectedTableIndex), '-1'));
                        selectedProduct = app.report_Table.Data.("Homologação")(idx);                        
                    end
            end

            if ~isempty(selectedTableIndex)                        
                app.listOfProducts(selectedTableIndex,:)    = [];                
                app.report_Table.Data(selectedTableIndex,:) = [];
                misc_Table_NumberOfRows(app, 'report')
            end

            if ~isempty(selectedProduct)
                app.search_ListOfProducts.Items = setdiff(app.search_ListOfProducts.Items, selectedProduct);
            end

            report_TableSelectionChanged(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Icon_36x36.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_Home = uitab(app.TabGroup);
            app.home_mainGrid = uigridlayout(app.Tab1_Home);
            app.home_Title = uilabel(app.home_mainGrid);
            app.home_Title.Interpreter = 'html';
            app.home_Title.Text = 'SCH<font style="font-size:12px;"> v. 0.01</font>';
            app.home_GIF = uiimage(app.home_mainGrid);
            app.home_GIF.Enable = 'off';
            app.home_GIF.ImageSource = fullfile(pathToMLAPP, 'Icons', 'SplashScreen.gif');
            app.Tab2_Search = uitab(app.TabGroup);
            app.search_mainGrid = uigridlayout(app.Tab2_Search);
            app.search_Document = uigridlayout(app.search_mainGrid);
            app.search_Table = uitable(app.search_Document);
            app.search_Table.ColumnName = {'HOMOLOGAÇÃO'; 'TIPO'; 'SOLICITANTE'; 'CNPJ/CPF'; 'FABRICANTE'; 'MODELO'; 'NOME COMERCIAL'; 'SITUAÇÃO'};
            app.search_Table.RowName = {};
            app.search_Table.SelectionType = 'row';
            app.search_Table.SelectionChangedFcn = createCallbackFcn(app, @search_Table_SelectionChanged, true);
            app.search_Table.Visible = 'off';
            app.search_entryPointPanel = uipanel(app.search_Document);
            app.search_entryPointGrid = uigridlayout(app.search_entryPointPanel);
            app.search_entryPoint = uieditfield(app.search_entryPointGrid, 'text');
            app.search_entryPoint.CharacterLimits = [0 128];
            app.search_entryPoint.ValueChangingFcn = createCallbackFcn(app, @search_EntryPoint_ValueChanging, true);
            app.search_entryPoint.Tag = 'PROMPT';
            app.search_entryPoint.Value = 'O que você quer pesquisar?';
            app.search_entryPointImage = uiimage(app.search_entryPointGrid);
            app.search_entryPointImage.ImageClickedFcn = createCallbackFcn(app, @search_EntryPoint_ImageClicked, true);
            app.search_entryPointImage.Enable = 'off';
            app.search_entryPointImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Zoom_36x36.png');
            app.search_Metadata = uilabel(app.search_Document);
            app.search_Metadata.Visible = 'off';
            app.search_Metadata.Interpreter = 'html';
            app.search_Metadata.Text = {'Exibindo resultados para "<b>apple iphone</b>"'; '<p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p><p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p>'};
            app.search_nRows = uilabel(app.search_Document);
            app.search_nRows.Visible = 'off';
            app.search_nRows.Text = '# 0 ';
            app.search_Suggestions = uilistbox(app.search_Document);
            app.search_Suggestions.Items = {''};
            app.search_Suggestions.Tag = 'CAIXA DE BUSCA';
            app.search_Suggestions.Visible = 'off';
            app.search_Suggestions.Value = {};
            app.search_Control = uigridlayout(app.search_mainGrid);
            app.search_ControlTabGroup = uitabgroup(app.search_Control);
            app.search_Tab1 = uitab(app.search_ControlTabGroup);
            app.search_Tab1Grid = uigridlayout(app.search_Tab1);
            app.search_ProductInfoLabel = uilabel(app.search_Tab1Grid);
            app.search_ProductInfoLabel.Text = 'PRODUTO SELECIONADO';
            app.search_ToolbarWordCloud = uiimage(app.search_Tab1Grid);
            app.search_ToolbarWordCloud.ImageClickedFcn = createCallbackFcn(app, @search_Panel_ToolbarButtonClicked, true);
            app.search_ToolbarWordCloud.Enable = 'off';
            app.search_ToolbarWordCloud.Tooltip = {'Nuvem de palavras'; '(Google/Bing)'};
            app.search_ToolbarWordCloud.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Cloud_32x32Gray.png');
            app.search_ToolbarListOfProducts = uiimage(app.search_Tab1Grid);
            app.search_ToolbarListOfProducts.ImageClickedFcn = createCallbackFcn(app, @search_Panel_ToolbarButtonClicked, true);
            app.search_ToolbarListOfProducts.Enable = 'off';
            app.search_ToolbarListOfProducts.Tooltip = {'Lista de produtos homologados sob anáilse'};
            app.search_ToolbarListOfProducts.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Box_32x32Gray.png');
            app.search_ProductInfoPanel = uipanel(app.search_Tab1Grid);
            app.search_ProductInfoGrid = uigridlayout(app.search_ProductInfoPanel);
            app.search_ProductInfo = uihtml(app.search_ProductInfoGrid);
            app.search_ProductInfo.HTMLSource = 'Warning.html';
            app.search_AnnotationPanelLabel = uilabel(app.search_Tab1Grid);
            app.search_AnnotationPanelLabel.Text = 'ANOTAÇÃO';
            app.search_AnnotationPanel = uipanel(app.search_Tab1Grid);
            app.search_AnnotationGrid = uigridlayout(app.search_AnnotationPanel);
            app.search_AnnotationAttributeLabel = uilabel(app.search_AnnotationGrid);
            app.search_AnnotationAttributeLabel.Text = 'Atributo:';
            app.search_AnnotationAttribute = uidropdown(app.search_AnnotationGrid);
            app.search_AnnotationAttribute.Items = {'Fornecedor', 'Fabricante', 'Modelo', 'EAN', 'Outras informações'};
            app.search_AnnotationAttribute.Value = 'Fornecedor';
            app.search_AnnotationValueLabel = uilabel(app.search_AnnotationGrid);
            app.search_AnnotationValueLabel.Text = 'Valor:';
            app.search_AnnotationValue = uitextarea(app.search_AnnotationGrid);
            app.search_AnnotationPanelAdd = uiimage(app.search_AnnotationGrid);
            app.search_AnnotationPanelAdd.ImageClickedFcn = createCallbackFcn(app, @search_Annotation_AddImageClicked, true);
            app.search_AnnotationPanelAdd.Enable = 'off';
            app.search_AnnotationPanelAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'NewFile_36.png');
            app.search_WordCloudPanel = uipanel(app.search_Tab1Grid);
            app.search_WordCloudRefreshGrid = uigridlayout(app.search_Tab1Grid);
            app.search_WordCloudRefresh = uiimage(app.search_WordCloudRefreshGrid);
            app.search_WordCloudRefresh.ImageClickedFcn = createCallbackFcn(app, @search_WordCloud_RefreshImageClicked, true);
            app.search_WordCloudRefresh.Enable = 'off';
            app.search_WordCloudRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18x18Gray.png');
            app.search_ListOfProductsLabel = uilabel(app.search_Tab1Grid);
            app.search_ListOfProductsLabel.Text = 'LISTA DE PRODUTOS HOMOLOGADOS SOB ANÁLISE';
            app.search_ListOfProductsAdd = uiimage(app.search_Tab1Grid);
            app.search_ListOfProductsAdd.ImageClickedFcn = createCallbackFcn(app, @search_Report_ListOfProductsAddImageClicked, true);
            app.search_ListOfProductsAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Sum_36.png');
            app.search_ListOfProducts = uilistbox(app.search_Tab1Grid);
            app.search_ListOfProducts.Items = {};
            app.search_ListOfProducts.Multiselect = 'on';
            app.search_ListOfProducts.Value = {};
            app.search_ToolbarAnnotation = uiimage(app.search_Tab1Grid);
            app.search_ToolbarAnnotation.ImageClickedFcn = createCallbackFcn(app, @search_Panel_ToolbarButtonClicked, true);
            app.search_ToolbarAnnotation.Enable = 'off';
            app.search_ToolbarAnnotation.Tooltip = {'Anotação textual'};
            app.search_ToolbarAnnotation.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_18x18Gray.png');
            app.search_Tab2 = uitab(app.search_ControlTabGroup);
            app.search_Tab2Grid = uigridlayout(app.search_Tab2);
            app.search_PrimaryPanelLabel = uilabel(app.search_Tab2Grid);
            app.search_PrimaryPanelLabel.Text = 'PRIMÁRIA';
            app.search_PrimaryPanel = uibuttongroup(app.search_Tab2Grid);
            app.search_PrimaryPanel.SelectionChangedFcn = createCallbackFcn(app, @search_Filtering_primaryTableColumnsChanged, true);
            app.search_PrimaryOption1 = uiradiobutton(app.search_PrimaryPanel);
            app.search_PrimaryOption1.Text = 'Homologação';
            app.search_PrimaryOption2 = uiradiobutton(app.search_PrimaryPanel);
            app.search_PrimaryOption2.Text = 'Solicitante | Fabricante';
            app.search_PrimaryOption3 = uiradiobutton(app.search_PrimaryPanel);
            app.search_PrimaryOption3.Text = 'Modelo | Nome Comercial';
            app.search_PrimaryOption3.Value = true;
            app.search_SecundaryPanelLabel = uilabel(app.search_Tab2Grid);
            app.search_SecundaryPanelLabel.Text = 'SECUNDÁRIA';
            app.search_SecundaryPanel = uipanel(app.search_Tab2Grid);
            app.search_SecundaryGrid = uigridlayout(app.search_SecundaryPanel);
            app.search_CertificationCheckBox = uicheckbox(app.search_SecundaryGrid);
            app.search_CertificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterCheckBoxClicked, true);
            app.search_CertificationCheckBox.Enable = 'off';
            app.search_CertificationCheckBox.Text = 'Homologação';
            app.search_Certification = uieditfield(app.search_SecundaryGrid, 'text');
            app.search_Certification.ValueChangingFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterValueChanging, true);
            app.search_Certification.Tag = 'Homologação';
            app.search_Certification.Enable = 'off';
            app.search_ProductListCheckBox = uicheckbox(app.search_SecundaryGrid);
            app.search_ProductListCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterCheckBoxClicked, true);
            app.search_ProductListCheckBox.Enable = 'off';
            app.search_ProductListCheckBox.Text = 'Tipo';
            app.search_ProductList = uidropdown(app.search_SecundaryGrid);
            app.search_ProductList.Items = {''};
            app.search_ProductList.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterValueChanging, true);
            app.search_ProductList.Tag = 'Tipo';
            app.search_ProductList.Enable = 'off';
            app.search_ProductList.Value = '';
            app.search_ApplicantCheckBox = uicheckbox(app.search_SecundaryGrid);
            app.search_ApplicantCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterCheckBoxClicked, true);
            app.search_ApplicantCheckBox.Enable = 'off';
            app.search_ApplicantCheckBox.Text = 'Solicitante';
            app.search_Applicant = uieditfield(app.search_SecundaryGrid, 'text');
            app.search_Applicant.ValueChangingFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterValueChanging, true);
            app.search_Applicant.Tag = 'Solicitante';
            app.search_Applicant.Enable = 'off';
            app.search_CNPJCheckBox = uicheckbox(app.search_SecundaryGrid);
            app.search_CNPJCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterCheckBoxClicked, true);
            app.search_CNPJCheckBox.Enable = 'off';
            app.search_CNPJCheckBox.Text = 'CNPJ/CPF';
            app.search_CNPJ = uieditfield(app.search_SecundaryGrid, 'text');
            app.search_CNPJ.ValueChangingFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterValueChanging, true);
            app.search_CNPJ.Tag = 'CNPJ/CPF';
            app.search_CNPJ.Enable = 'off';
            app.search_ManufacturerCheckBox = uicheckbox(app.search_SecundaryGrid);
            app.search_ManufacturerCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterCheckBoxClicked, true);
            app.search_ManufacturerCheckBox.Enable = 'off';
            app.search_ManufacturerCheckBox.Text = 'Fabricante';
            app.search_Manufacturer = uieditfield(app.search_SecundaryGrid, 'text');
            app.search_Manufacturer.ValueChangingFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterValueChanging, true);
            app.search_Manufacturer.Tag = 'Fabricante';
            app.search_Manufacturer.Enable = 'off';
            app.search_ModelCheckBox = uicheckbox(app.search_SecundaryGrid);
            app.search_ModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterCheckBoxClicked, true);
            app.search_ModelCheckBox.Enable = 'off';
            app.search_ModelCheckBox.Text = 'Modelo';
            app.search_Model = uieditfield(app.search_SecundaryGrid, 'text');
            app.search_Model.ValueChangingFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterValueChanging, true);
            app.search_Model.Tag = 'Modelo';
            app.search_Model.Enable = 'off';
            app.search_TradeNameCheckBox = uicheckbox(app.search_SecundaryGrid);
            app.search_TradeNameCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterCheckBoxClicked, true);
            app.search_TradeNameCheckBox.Enable = 'off';
            app.search_TradeNameCheckBox.Text = 'Nome Comercial';
            app.search_TradeName = uieditfield(app.search_SecundaryGrid, 'text');
            app.search_TradeName.ValueChangingFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterValueChanging, true);
            app.search_TradeName.Tag = 'Nome Comercial';
            app.search_TradeName.Enable = 'off';
            app.search_StatusCheckBox = uicheckbox(app.search_SecundaryGrid);
            app.search_StatusCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterCheckBoxClicked, true);
            app.search_StatusCheckBox.Enable = 'off';
            app.search_StatusCheckBox.Text = 'Situação';
            app.search_Status = uidropdown(app.search_SecundaryGrid);
            app.search_Status.Items = {''};
            app.search_Status.ValueChangedFcn = createCallbackFcn(app, @search_Filtering_secundaryFilterValueChanging, true);
            app.search_Status.Tag = 'Situação';
            app.search_Status.Enable = 'off';
            app.search_Status.Value = '';
            app.search_ControlMenu = uigridlayout(app.search_Control);
            app.search_menuUnderline = uiimage(app.search_ControlMenu);
            app.search_menuUnderline.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineH.png');
            app.search_menuBtn1Grid = uigridlayout(app.search_ControlMenu);
            app.search_menuBtn1Label = uilabel(app.search_menuBtn1Grid);
            app.search_menuBtn1Label.Text = 'Aspectos gerais';
            app.search_menuBtn1Icon = uiimage(app.search_menuBtn1Grid);
            app.search_menuBtn1Icon.ImageClickedFcn = createCallbackFcn(app, @misc_Panel_TabControlButtonPushed, true);
            app.search_menuBtn1Icon.Tag = '1';
            app.search_menuBtn1Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Connect_18x18.png');
            app.search_menuBtn2Grid = uigridlayout(app.search_ControlMenu);
            app.search_menuBtn2Label = uilabel(app.search_menuBtn2Grid);
            app.search_menuBtn2Label.Text = 'Filtragem';
            app.search_menuBtn2Icon = uiimage(app.search_menuBtn2Grid);
            app.search_menuBtn2Icon.ImageClickedFcn = createCallbackFcn(app, @misc_Panel_TabControlButtonPushed, true);
            app.search_menuBtn2Icon.Tag = '2';
            app.search_menuBtn2Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Filter_18x18.png');
            app.Tab3_Report = uitab(app.TabGroup);
            app.report_mainGrid = uigridlayout(app.Tab3_Report);
            app.report_Document = uigridlayout(app.report_mainGrid);
            app.report_TableLabel = uilabel(app.report_Document);
            app.report_TableLabel.Text = 'LISTA DE PRODUTOS SOB ANÁLISE';
            app.report_Table = uitable(app.report_Document);
            app.report_Table.ColumnName = {'HOMOLOGAÇÃO'; 'TIPO'; 'FABRICANTE'; 'MODELO'; 'RF?'; 'EM USO?'; 'INTERFERÊNCIA?'; 'VALOR|UNITÁRIO (R$)'; 'QTD.|USO/VENDIDA'; 'QTD.|ESTOQUE'; 'QTD.|LACRADAS'; 'QTD.|APREENDIDAS'; 'QTD.|RETIDAS (RFB)'};
            app.report_Table.RowName = {};
            app.report_Table.SelectionType = 'row';
            app.report_Table.ColumnEditable = [false true true true true true true true true true true true true];
            app.report_Table.CellEditCallback = createCallbackFcn(app, @report_TableCellEdit, true);
            app.report_Table.SelectionChangedFcn = createCallbackFcn(app, @report_TableSelectionChanged, true);
            app.report_nRows = uilabel(app.report_Document);
            app.report_nRows.Text = '# 0 ';
            app.report_Control = uigridlayout(app.report_mainGrid);
            app.report_ControlTabGroup = uitabgroup(app.report_Control);
            app.report_Tab1 = uitab(app.report_ControlTabGroup);
            app.report_Tab1Grid = uigridlayout(app.report_Tab1);
            app.report_ProductInfoLabel = uilabel(app.report_Tab1Grid);
            app.report_ProductInfoLabel.Text = 'PRODUTO SELECIONADO';
            app.report_ProductInfoPanel = uipanel(app.report_Tab1Grid);
            app.report_ProductInfoGrid = uigridlayout(app.report_ProductInfoPanel);
            app.report_ProductInfo = uihtml(app.report_ProductInfoGrid);
            app.report_ProductInfo.HTMLSource = 'Warning.html';
            app.report_EditableInfoLabel = uilabel(app.report_Tab1Grid);
            app.report_EditableInfoLabel.Text = 'INCLUSÃO';
            app.report_EditableInfoPanel = uipanel(app.report_Tab1Grid);
            app.report_EditableInfoGrid = uigridlayout(app.report_EditableInfoPanel);
            app.report_productSituationPanel = uibuttongroup(app.report_EditableInfoGrid);
            app.report_productSituationPanel.SelectionChangedFcn = createCallbackFcn(app, @report_productSituationPanelSelectionChanged, true);
            app.report_productSituation1 = uiradiobutton(app.report_productSituationPanel);
            app.report_productSituation1.Text = 'HOMOLOGADO';
            app.report_productSituation1.Interpreter = 'html';
            app.report_productSituation2 = uiradiobutton(app.report_productSituationPanel);
            app.report_productSituation2.Text = '<font style="color:red;">NÃO</font> HOMOLOGADO';
            app.report_productSituation2.Interpreter = 'html';
            app.report_productSituation2.Value = true;
            app.report_SituationLabel = uilabel(app.report_EditableInfoGrid);
            app.report_SituationLabel.Text = 'Situação:';
            app.report_Situation = uidropdown(app.report_EditableInfoGrid);
            app.report_Situation.Items = {'Irregular', 'Regular'};
            app.report_Situation.Value = 'Irregular';
            app.report_ViolationLabel = uilabel(app.report_EditableInfoGrid);
            app.report_ViolationLabel.Text = 'Infração:';
            app.report_Violation = uidropdown(app.report_EditableInfoGrid);
            app.report_Violation.Items = {'', 'Comercialização', 'Identificação homologação', 'Uso'};
            app.report_Violation.Value = '';
            app.report_NotesLabel = uilabel(app.report_EditableInfoGrid);
            app.report_NotesLabel.Text = 'Informações adicionais:';
            app.report_Notes = uitextarea(app.report_EditableInfoGrid);
            app.report_EditOrAddButton = uiimage(app.report_EditableInfoGrid);
            app.report_EditOrAddButton.ImageClickedFcn = createCallbackFcn(app, @report_EditOrAddButtonImageClicked, true);
            app.report_EditOrAddButton.ImageSource = 'NewFile_36.png';
            app.report_nHomLabel = uilabel(app.report_EditableInfoGrid);
            app.report_nHomLabel.Text = 'Homologação:';
            app.report_nHom = uieditfield(app.report_EditableInfoGrid, 'text');
            app.report_nHom.CharacterLimits = [0 14];
            app.report_nHom.Value = '-1';
            app.report_ToolbarEditOrAdd = uiimage(app.report_Tab1Grid);
            app.report_ToolbarEditOrAdd.ImageClickedFcn = createCallbackFcn(app, @report_ToolbarEditOrAddImageClicked, true);
            app.report_ToolbarEditOrAdd.ImageSource = 'Edit_18x18Gray.png';
            app.report_Tab2 = uitab(app.report_ControlTabGroup);
            app.report_Tab2Grid = uigridlayout(app.report_Tab2);
            app.report_ProjectLabel = uilabel(app.report_Tab2Grid);
            app.report_ProjectLabel.Text = 'PROJETO';
            app.report_ProjectPanel = uipanel(app.report_Tab2Grid);
            app.report_ProjectGrid = uigridlayout(app.report_ProjectPanel);
            app.report_ProjectNameLabel = uilabel(app.report_ProjectGrid);
            app.report_ProjectNameLabel.Text = 'Arquivo:';
            app.report_ProjectNew = uiimage(app.report_ProjectGrid);
            app.report_ProjectNew.ImageSource = fullfile(pathToMLAPP, 'Icons', 'AddFiles_36.png');
            app.report_ProjectOpen = uiimage(app.report_ProjectGrid);
            app.report_ProjectOpen.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.report_ProjectSave = uiimage(app.report_ProjectGrid);
            app.report_ProjectSave.ImageSource = fullfile(pathToMLAPP, 'Icons', 'SaveFile_36.png');
            app.report_ProjectName = uitextarea(app.report_ProjectGrid);
            app.report_ProjectName.Editable = 'off';
            app.report_FiscalizaLabel = uilabel(app.report_Tab2Grid);
            app.report_FiscalizaLabel.Text = 'FISCALIZAÇÃO';
            app.report_FiscalizaPanel = uipanel(app.report_Tab2Grid);
            app.report_FiscalizaGrid = uigridlayout(app.report_FiscalizaPanel);
            app.report_IssueLabel = uilabel(app.report_FiscalizaGrid);
            app.report_IssueLabel.Text = 'Inspeção:';
            app.report_Issue = uieditfield(app.report_FiscalizaGrid, 'numeric');
            app.report_Issue.Limits = [-1 Inf];
            app.report_Issue.RoundFractionalValues = 'on';
            app.report_Issue.ValueDisplayFormat = '%d';
            app.report_Issue.Value = -1;
            app.report_EntityLabel = uilabel(app.report_FiscalizaGrid);
            app.report_EntityLabel.Text = 'Fiscalizada:';
            app.report_Entity = uieditfield(app.report_FiscalizaGrid, 'text');
            app.report_EntityIDLabel = uilabel(app.report_FiscalizaGrid);
            app.report_EntityIDLabel.Text = 'CNPJ/CPF:';
            app.report_EntityID = uieditfield(app.report_FiscalizaGrid, 'text');
            app.report_EntityTypeLabel = uilabel(app.report_FiscalizaGrid);
            app.report_EntityTypeLabel.Text = 'Tipo:';
            app.report_EntityType = uidropdown(app.report_FiscalizaGrid);
            app.report_EntityType.Items = {'', 'Fornecedor', 'Usuário'};
            app.report_EntityType.Value = '';
            app.report_ReportLabel = uilabel(app.report_Tab2Grid);
            app.report_ReportLabel.Text = 'RELATÓRIO';
            app.report_ReportPanel = uipanel(app.report_Tab2Grid);
            app.report_ReportGrid = uigridlayout(app.report_ReportPanel);
            app.report_ModelLabel = uilabel(app.report_ReportGrid);
            app.report_ModelLabel.Text = 'Modelo:';
            app.report_Model = uidropdown(app.report_ReportGrid);
            app.report_Model.Items = {};
            app.report_Model.Value = {};
            app.report_VersionLabel = uilabel(app.report_ReportGrid);
            app.report_VersionLabel.Text = 'Versão:';
            app.report_Version = uidropdown(app.report_ReportGrid);
            app.report_Version.Items = {'Preliminar', 'Definitiva'};
            app.report_Version.Value = 'Preliminar';
            app.report_ControlMenu = uigridlayout(app.report_Control);
            app.report_menuUnderline = uiimage(app.report_ControlMenu);
            app.report_menuUnderline.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineH.png');
            app.report_menuBtn1Grid = uigridlayout(app.report_ControlMenu);
            app.report_menuBtn1Label = uilabel(app.report_menuBtn1Grid);
            app.report_menuBtn1Label.Text = 'Aspectos gerais';
            app.report_menuBtn1Icon = uiimage(app.report_menuBtn1Grid);
            app.report_menuBtn1Icon.ImageClickedFcn = createCallbackFcn(app, @misc_Panel_TabControlButtonPushed, true);
            app.report_menuBtn1Icon.Tag = '1';
            app.report_menuBtn1Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Connect_18x18.png');
            app.report_menuBtn2Grid = uigridlayout(app.report_ControlMenu);
            app.report_menuBtn2Label = uilabel(app.report_menuBtn2Grid);
            app.report_menuBtn2Label.Text = 'Fiscalização';
            app.report_menuBtn2Icon = uiimage(app.report_menuBtn2Grid);
            app.report_menuBtn2Icon.ImageClickedFcn = createCallbackFcn(app, @misc_Panel_TabControlButtonPushed, true);
            app.report_menuBtn2Icon.Tag = '2';
            app.report_menuBtn2Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Report_18x18.png');
            app.Tab4_Config = uitab(app.TabGroup);
            app.config_mainGrid = uigridlayout(app.Tab4_Config);
            app.config_SearchModeLabel = uilabel(app.config_mainGrid);
            app.config_SearchModeLabel.Text = 'MODO DE PESQUISA';
            app.config_SearchModePanel = uibuttongroup(app.config_mainGrid);
            app.config_SearchModePanel.SelectionChangedFcn = createCallbackFcn(app, @config_SearchModeChanged, true);
            app.config_SearchModeTokenSuggestion = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeTokenSuggestion.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à palavra que está sendo escrita, sugerindo <i>tokens</i> relacionados.</p>';
            app.config_SearchModeTokenSuggestion.Interpreter = 'html';
            app.config_SearchModeTokenSuggestion.Value = true;
            app.config_SearchModeListOfWords = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeListOfWords.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à uma lista de palavras separadas por vírgulas. Não há sugestão de <i>tokens</i> relacionados.</p>';
            app.config_SearchModeListOfWords.Interpreter = 'html';
            app.config_MiscelaneousLabel1 = uilabel(app.config_mainGrid);
            app.config_MiscelaneousLabel1.Text = 'ALGORITMO SUGESTÃO DE TOKENS';
            app.config_MiscelaneousPanel1 = uipanel(app.config_mainGrid);
            app.config_MiscelaneousGrid1 = uigridlayout(app.config_MiscelaneousPanel1);
            app.config_nMinCharactersLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinCharactersLabel.Text = {'Qtd. mínima'; 'caracteres:'};
            app.config_nMinCharacters = uispinner(app.config_MiscelaneousGrid1);
            app.config_nMinCharacters.Limits = [2 4];
            app.config_nMinCharacters.RoundFractionalValues = 'on';
            app.config_nMinCharacters.ValueDisplayFormat = '%d';
            app.config_nMinCharacters.Value = 2;
            app.config_nMinWordsLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinWordsLabel.Interpreter = 'html';
            app.config_nMinWordsLabel.Text = {'Qtd. mínima '; '<i>tokens</i>:'};
            app.config_nMinWords = uidropdown(app.config_MiscelaneousGrid1);
            app.config_nMinWords.Items = {'20', '50', '100'};
            app.config_nMinWords.Value = '20';
            app.config_MiscelaneousLabel2 = uilabel(app.config_mainGrid);
            app.config_MiscelaneousLabel2.Text = 'ANOTAÇÃO DO TIPO "WORDCLOUD"';
            app.config_MiscelaneousPanel2 = uipanel(app.config_mainGrid);
            app.config_MiscelaneousGrid2 = uigridlayout(app.config_MiscelaneousPanel2);
            app.config_WordCloudColumnLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumnLabel.Text = {'Busca de palavras '; 'orientada à coluna:'};
            app.config_WordCloudColumn = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumn.Items = {'Modelo', 'Nome Comercial'};
            app.config_WordCloudColumn.Value = 'Modelo';
            app.config_WordCloudNumberLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudNumberLabel.Text = {'Qtd. máxima'; 'palavras:'};
            app.config_WordCloudNumber = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudNumber.Items = {'10', '25', '50'};
            app.config_WordCloudNumber.ValueChangedFcn = createCallbackFcn(app, @config_NumberOfWordsCloudValueChanged, true);
            app.config_WordCloudNumber.Value = '25';
            app.config_FolderMapLabel = uilabel(app.config_mainGrid);
            app.config_FolderMapLabel.Text = 'MAPEAMENTO DE PASTAS';
            app.config_SelectedTableColumnsLabel = uilabel(app.config_mainGrid);
            app.config_SelectedTableColumnsLabel.Text = 'VISIBILIDADE DE COLUNAS';
            app.config_SelectedTableColumns = uitree(app.config_mainGrid, 'checkbox');
            % Assign Checked Nodes
            app.config_SelectedTableColumns.CheckedNodesChangedFcn = createCallbackFcn(app, @config_ListOfColumns2ShowChanged, true);
            app.config_FolderMapPanel = uipanel(app.config_mainGrid);
            app.config_FolderMapGrid = uigridlayout(app.config_FolderMapPanel);
            app.config_Folder_DataHubGETLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETLabel.Text = 'DataHub - GET';
            app.config_Folder_DataHubGET = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubGET.Editable = 'off';
            app.config_Folder_DataHubGETButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETButton.ImageClickedFcn = createCallbackFcn(app, @config_getFolder, true);
            app.config_Folder_DataHubGETButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_DataHubPOSTLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubPOSTLabel.Text = 'DataHub - POST';
            app.config_Folder_DataHubPOST = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubPOST.Editable = 'off';
            app.config_Folder_DataHubPOSTButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @config_getFolder, true);
            app.config_Folder_DataHubPOSTButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_UserPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_UserPathLabel.Text = 'Pasta do usuário:';
            app.config_Folder_UserPath = uidropdown(app.config_FolderMapGrid);
            app.config_Folder_UserPath.Items = {''};
            app.config_Folder_UserPath.Value = '';
            app.config_Folder_UserPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_UserPathButton.ImageClickedFcn = createCallbackFcn(app, @config_getFolder, true);
            app.config_Folder_UserPathButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.menu_Grid = uigridlayout(app.GridLayout);
            app.menu_Grid.Tag = 'COLORLOCKED';
            app.menu_Button1 = uibutton(app.menu_Grid, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button1.Tag = '1';
            app.menu_Button1.Enable = 'off';
            app.menu_Button1.Tooltip = {''};
            app.menu_Button1.Icon = fullfile(pathToMLAPP, 'Icons', 'Home_32Yellow.png');
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            app.menu_Button2 = uibutton(app.menu_Grid, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button2.Tag = '2';
            app.menu_Button2.Enable = 'off';
            app.menu_Button2.Tooltip = {''};
            app.menu_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'Zoom_32White.png');
            app.menu_Button2.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button3.Tag = '3';
            app.menu_Button3.Enable = 'off';
            app.menu_Button3.Tooltip = {''};
            app.menu_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'Detection_32White.png');
            app.menu_Button3.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button4.Tag = '4';
            app.menu_Button4.Enable = 'off';
            app.menu_Button4.Tooltip = {''};
            app.menu_Button4.Icon = fullfile(pathToMLAPP, 'Icons', 'Settings_36White.png');
            app.menu_Button4.Text = '';
            app.search_PanelVisibility = uiimage(app.menu_Grid);
            app.search_PanelVisibility.ImageClickedFcn = createCallbackFcn(app, @misc_Panel_VisibilityImageClicked, true);
            app.search_PanelVisibility.Visible = 'off';
            app.search_PanelVisibility.ImageSource = fullfile(pathToMLAPP, 'Icons', 'ArrowRight_32White.png');
            app.report_PanelVisibility = uiimage(app.menu_Grid);
            app.report_PanelVisibility.ImageClickedFcn = createCallbackFcn(app, @misc_Panel_VisibilityImageClicked, true);
            app.report_PanelVisibility.Visible = 'off';
            app.report_PanelVisibility.ImageSource = fullfile(pathToMLAPP, 'Icons', 'ArrowRight_32White.png');
            app.report_ReportPreview = uiimage(app.menu_Grid);
            app.report_ReportPreview.ImageClickedFcn = createCallbackFcn(app, @report_ReportPreviewImageClicked, true);
            app.report_ReportPreview.Visible = 'off';
            app.report_ReportPreview.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Play_36White.png');
            app.DataHubLamp = uilamp(app.menu_Grid);
            app.DataHubLamp.Enable = 'off';
            app.DataHubLamp.Visible = 'off';
            app.DataHubLamp.Tooltip = {''};
            app.FigurePosition = uiimage(app.menu_Grid);
            app.FigurePosition.ImageClickedFcn = createCallbackFcn(app, @menu_auxiliarButtonPushed, true);
            app.FigurePosition.Enable = 'off';
            app.FigurePosition.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Layout1.png');
            app.AppInfo = uiimage(app.menu_Grid);
            app.AppInfo.ImageClickedFcn = createCallbackFcn(app, @menu_auxiliarButtonPushed, true);
            app.AppInfo.Enable = 'off';
            app.AppInfo.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Dots_36x36W.png');
            app.jsBackDoor = uihtml(app.menu_Grid);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu_DeleteFcn = uimenu(app.ContextMenu);
            app.ContextMenu_DeleteFcn.MenuSelectedFcn = createCallbackFcn(app, @ContextMenu_DeleteFcnSelected, true);
            app.ContextMenu_DeleteFcn.Text = 'Excluir';
            % Assign app.ContextMenu
            app.search_ListOfProducts.ContextMenu = app.ContextMenu;
            app.report_Table.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSCH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end